classdef imgRegMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout9                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        DataTab                       matlab.ui.container.Tab
        dataGrid                      matlab.ui.container.GridLayout
        dataButtonGrid                matlab.ui.container.GridLayout
        ChoosepreferenceButton        matlab.ui.control.Button
        SetapppreferencesButton       matlab.ui.control.Button
        LoadfolderButton              matlab.ui.control.Button
        LoadfilesButton               matlab.ui.control.Button
        RemoveselectedrowsButton      matlab.ui.control.Button
        SavetoworkspaceButton         matlab.ui.control.Button
        visibleData                   matlab.ui.control.Table
        ImageTab                      matlab.ui.container.Tab
        imageGrid                     matlab.ui.container.GridLayout
        GridLayout12                  matlab.ui.container.GridLayout
        SavetoworkspaceButton_2       matlab.ui.control.Button
        ResettransformsButton         matlab.ui.control.Button
        imageVersionDropDown          matlab.ui.control.DropDown
        SelectionAmountButtonGroup    matlab.ui.container.ButtonGroup
        UseNRecSpinner                matlab.ui.control.Spinner
        UseallCheckBox                matlab.ui.control.CheckBox
        RemovingOldestSwitch          matlab.ui.control.Switch
        ResetcolorsCheckBox           matlab.ui.control.CheckBox
        ColorSortingSwitch            matlab.ui.control.Switch
        TabGroup2                     matlab.ui.container.TabGroup
        AutoTab                       matlab.ui.container.Tab
        GridLayout_AutoMove           matlab.ui.container.GridLayout
        FullviewCheckBox              matlab.ui.control.CheckBox
        RegisterimagesButton          matlab.ui.control.Button
        ManualTab                     matlab.ui.container.Tab
        GridLayoutManualMove          matlab.ui.container.GridLayout
        SpeedofchangeviascrollSlider  matlab.ui.control.Slider
        SpeedofchangeviascrollSliderLabel  matlab.ui.control.Label
        UITableTransform              matlab.ui.control.Table
        UIAxesScrollManual            matlab.ui.control.UIAxes
        CtrlptsTab                    matlab.ui.container.Tab
        GridLayout10                  matlab.ui.container.GridLayout
        Label2                        matlab.ui.control.Label
        RemovecontrolpointsButton     matlab.ui.control.Button
        UndolastButton                matlab.ui.control.Button
        GridLayout11                  matlab.ui.container.GridLayout
        imageTable                    matlab.ui.control.Table
        GridLayout13                  matlab.ui.container.GridLayout
        Label_corr                    matlab.ui.control.Label
        UIAxes_corr                   matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        linkROIsTab                   matlab.ui.container.Tab
        GridLayoutLinkROIs            matlab.ui.container.GridLayout
        GridLayoutLinkROIsLeft        matlab.ui.container.GridLayout
        ShowingpercentagesButton      matlab.ui.control.Button
        UIAxesRoiLinksSup1            matlab.ui.control.UIAxes
        UIAxesRoiLinksSup2            matlab.ui.control.UIAxes
        UIAxesRoiLinksSup3            matlab.ui.control.UIAxes
        GridLayoutLinkROIsRight       matlab.ui.container.GridLayout
        ChronicThresholdSlider        matlab.ui.control.Slider
        ChronicReloadandRecalculatewhenswitchingtabsCheckBox  matlab.ui.control.CheckBox
        ChronicViewDropDown           matlab.ui.control.DropDown
        ChronicViewerUIButton         matlab.ui.control.Button
        LoadChronicEditsButton        matlab.ui.control.Button
        ChronicSavetoFileButton       matlab.ui.control.Button
        ThresholdSliderLabel          matlab.ui.control.Label
        UIAxesColorbar                matlab.ui.control.UIAxes
        UIAxesRoiLinks                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        config struct %struct representing the config file
        process_load_data_table table %table needed to process additional functions
        previousPivotIndex int16 = 0 %index for changing pivot
        prevSelect logical = 0 %index for changing select related to pivot
        data table %Master table where everything is stored
        corr_maxVal double = NaN % Correlation imge text
        
        outputView imref2d %imgref to plot multiple recordings relative to each other
        %added selMemory to the app INIT or it hasn't yet done the startup
        %function
        selMemory % basicQueue = basicQueue(); memory of order in which images are selected, make it a property but init in initImage
        selMemState struct %struct to store memory state
        
        ctrlPts struct % data about manual control point registration
        hmainImg matlab.graphics.Graphics % the main image of the data
        
        linkROIs struct % data about chronically linking ROIs
        h struct % A struct for more graphics handles

        %You can change what the following columns appear as in the UI
        %the dictionaries contain key-value pairs, the key must remain the
        %same as that is what is referenced in the program, the value
        %(which is displayed) can be changed to whatever you would like
        colName = dictionary("pivot", 'Pivot',   "select", 'Select',   "manual", 'Manual',    "ID", "ID",    "name", 'Name',   "path", 'Path') %column names for visible data
        colType = dictionary("pivot", 'logical', "select", 'logical', "manual", 'logical', "ID", "double","name", 'string', "path", 'string')
        hColName = dictionary("tform", 'tForm',         "wImg", "warpedImg", "imgColor", "imgColor") %column names for hidden data
        hColType = dictionary("tform", 'affinetform2d', "wImg", "cell",      "imgColor", "double")
        viewsuffix = "_view";
        rawsuffix = "_raw";
        
        %BUG WITH ID , WHEN DELETING ROWS THE ID GETS CORRUPTED
        %EITHER MAKE THE ID JUST THE ROW NUMBER; SO IT CAN CHANGE FOR EACH
        %IMAGE WHEN ROWS ARE DELETED; OR ADD A CONSTANT THAT SLOWLY
        %ITERATES FOR EACH ROW ADDED
        
        %ADD ANOTHER TOGGLE SO I CAN PLOT RAW IMAGES IN THE PLOTTER MAYBE A
        %BUTTON SO IT JUST PLOTS A TWO IMAGE PAIR, i.e. the single selected
        %image and it's raw counterpart
        
        %BUGS WHEN REGISTERING WITHOUT A PIVOT
        %
        %
        % 
    end
    
    methods (Description= "init", Access = private)
        
        function initWorkingDir(app)
            excludedPaths = {'\.git', '\docs'};
            currentDir = pwd;
            
            allPaths = genpath(currentDir);
            allPaths = strsplit(allPaths, pathsep);
            allPaths = allPaths(~cellfun('isempty', allPaths));
            keepPaths = true(size(allPaths));
            
            for i = 1: length(excludedPaths)
                keepPaths = keepPaths & ~contains(allPaths, excludedPaths{i});
            end
            
            filteredPaths = allPaths(keepPaths);
            
            for i = 1: length(filteredPaths)
                addpath(filteredPaths{i});
            end
            
            disp('Paths added to MATLAB:');
            disp(filteredPaths');
        end
        
        
        function initUITables(app)
            %INIT VISIBLE TABLE
            VTCols = [values(app.colName)];
            VTWidth =    [55, 60, 65, 50, 100];
            VTEditable = [true, true, false, false, false, false, false];
            for i=1:length(VTCols)
                if i<= length(VTWidth)
                    addUIColumn(app, app.visibleData, VTCols(i), VTEditable(i), VTWidth(i));
                else
                    addUIColumn(app, app.visibleData, VTCols(i));
                end
            end
            
            %INIT IMAGE VIEWER TABLE
            imgCols = [app.colName("pivot"), app.colName("select"), app.colName("manual"), app.colName("ID"),app.colName("IDName")];
            imgWidth = {45, 55, 55, 16, 'auto'};
            imgEditable = [true, true, true, false, false];
            for i=1:length(imgCols)
                if i<= length(imgWidth)
                    addUIColumn(app, app.imageTable, imgCols(i), imgEditable(i), imgWidth{i});
                else
                    addUIColumn(app, app.imageTable, imgCols(i));
                end
            end        
        end
        
        
        function initData(app)
            %INIT EXTRA COLUMN FUNCTIONS TABLE
            procColNames = {'output_cols', 'handles', 'input_cols'};
            procVarTypes = {'cell', 'cell', 'cell'};
            app.process_load_data_table = table('Size',[0,length(procColNames)],'VariableTypes',procVarTypes, 'VariableNames',procColNames);
            
            %INIT DATA TABLE
            colNames = [values(app.colName);values(app.hColName)];
            varTypes = [values(app.colType);values(app.hColType)];
            app.data = table('Size',[0,length(colNames)],'VariableTypes',varTypes, 'VariableNames',colNames);
            
            tempImages = cell(1, length(app.config.data.FetchDataCols));
            
            app.hmainImg = imagesc(0, 'Parent', app.UIAxes, 'HitTest', 'off');
            set([app.UIAxes, app.UIAxesRoiLinksSup1, app.UIAxesRoiLinksSup2, app.UIAxesRoiLinksSup3], 'NextPlot', 'add')
            
            % More graphical objects, for roi linking tab
            app.h(1).scoreScatter = scatter(nan, nan, 8, 'filled', 'Parent', app.UIAxesRoiLinksSup1);
            app.h.scoreLine = plot(nan, nan, 'ro-', 'Parent', app.UIAxesRoiLinksSup1);
            app.h.scoreThresLine = plot(nan, nan, '--k', 'Parent', app.UIAxesRoiLinksSup1);
            app.h.foundbackImg = imagesc(nan, 'Parent', app.UIAxesRoiLinksSup2);
            app.h.foundbackLine = plot(nan, nan, '-w', 'Parent', app.UIAxesRoiLinksSup2);
            app.h.foundbackText = text(app.UIAxesRoiLinksSup2, nan, nan, '');
            app.h.confusionImg = imagesc(nan, 'Parent', app.UIAxesRoiLinksSup3);
            app.h.confusionText = text(app.UIAxesRoiLinksSup3, nan, nan, '');
            
            % registration via control point data initialisation
            x = []; y = []; id = [];
            app.ctrlPts(1).movingPoints = table(x,y,id);
            app.ctrlPts.fixedPoints = table(x,y,id);
            app.ctrlPts.nmoving = 0;
            app.ctrlPts.nfixed  = 0;
            app.ctrlPts.hmoving = scatter(NaN,NaN,30,"yellow", 'Parent', app.UIAxes, 'HitTest', 'off');
            app.ctrlPts.hfixed = scatter(NaN,NaN,30,"yellow", 'x', 'Parent', app.UIAxes, 'HitTest', 'off');
            app.ctrlPts.hlines = plot(NaN,NaN,"w", 'LineWidth', 1.75, 'Parent', app.UIAxes, 'HitTest', 'off');
            
            %ADD ADDITIONAL NAME COLS
            for i=1:length(app.config.data.ExtraColumns)
                ProcessConfigColumn(app, app.config.data.ExtraColumns{i});
            end
            %ADD ADDITIONAL IMAGE COLS
            for i=1:length(app.config.data.FetchDataCols)
                tempImages{i} = ProcessConfigColumn(app, app.config.data.FetchDataCols{i});
            end
            
            %RESOLVE USER DEFINED COLUMNS
            app.process_load_data_table = ResolveProcessTableOrder(app, colNames, app.process_load_data_table);
            
            for i=1:height(app.process_load_data_table)
                col = app.process_load_data_table.output_cols{i};
                addColumn(app, col);
                app.colName = insert(app.colName, col,  col);
                %EACH IMAGE HAS A VIEW, A NAME TO DISPLAY, AND RAW IMAGE
                %ITSELF
                if ismember(col, tempImages)
                    colView = col + app.viewsuffix;
                    colRaw = col + app.rawsuffix;
                    
                    addColumn(app, colView);
                    app.hColName = insert(app.hColName,  colView,  colView);
                    addColumn(app, colRaw);
                    app.hColName = insert(app.hColName,  colRaw,  colRaw);
                    
                    app.imageVersionDropDown.Items = [app.imageVersionDropDown.Items, {col}];
                    
                    %CHANGE THE NAME FOR PROCESS LOAD TABLE TO TARGET THE
                    %RAW ARRAY
                    app.process_load_data_table.output_cols{i} = colRaw;
                end
            end
            
            %ADD FINALISED COLUMNS
            initUITables(app);
        end
        
        
        function newTable = ResolveProcessTableOrder(app, existCol, oldTable)
            newTable = oldTable;
            newTable(:, :) = [];

            while height(oldTable) > 0
                noResolution = true;
                for i=1:height(oldTable)
                    if all(ismember(oldTable.input_cols{i}, existCol))
                        noResolution = false;
                        %add check for duplicates
                        if ~checkDuplicates(i)
                            newTable = [newTable; oldTable(i, :)];
                            existCol = [existCol; oldTable.output_cols{i}];
                        end
                        oldTable(i, :) = [];
                        break;
                    end
                end
                if noResolution
                    error("Can't resolve added columns. Check for circular inputs in the function inputs")
                end
            end
            
            for i=1:height(newTable)
                newTable.handles(i) = {str2func(newTable.handles{i})};
            end
            
            function bool = checkDuplicates(row)
                bool = false;
                if isequal(height(newTable), 0)
                    return
                elseif ismember(oldTable.handles{row}, string(newTable.handles))
                    bool = true;
                    return
                elseif ismember(oldTable.output_cols{row}, string(newTable.output_cols))
                    error("Column names must be different, please remove duplicated: %s", oldTable.output_cols{row});
                end
            end
        end
        
        
        function addUIColumn(app, myTable, col_name, editable, col_width)
            arguments (Input)
                app 
                myTable
                col_name char
                editable logical = false
                col_width = 'auto'
            end
            if isequal(length(myTable.ColumnName), 0)
                myTable.ColumnName = {col_name};
                myTable.ColumnEditable = editable;
                myTable.ColumnWidth = {col_width};
            else
                myTable.ColumnName = [myTable.ColumnName', {col_name}]';
                myTable.ColumnEditable = [myTable.ColumnEditable, editable];
                myTable.ColumnWidth = [myTable.ColumnWidth, {col_width}];
            end
        end
        
        
        function col_name = ProcessConfigColumn(app, column_string)
            tokens = regexp(column_string, '(\w+)\s*=\s*(\w+)\(([\w, ]*)\)', 'tokens');% Extract components from prompt
            
            tokens = tokens{1}; % Extract inner cell array
            col_name = tokens{1};      % column label
            func_to_run = tokens{2};    % function to run
            func_inputs = strtrim(tokens{3});  %function inputs
            func_inputs_arr = strtrim(strsplit(func_inputs, ','));
            % Construct the function string for str2func
            func_str = sprintf("@(%s) %s(%s)", func_inputs, func_to_run, func_inputs);
            
            new_row = {col_name, {func_str}, {func_inputs_arr}'};
            app.process_load_data_table = [app.process_load_data_table; new_row];
        end
        
        
        function addColumn(app, col)
            arguments (Input)
                app 
                col string
            end
            
            if ~ismember(col, app.data.Properties.VariableNames)
                app.data.(col) = cell(0,1);
            else
                error("added column %s has same name as internal column %s, please change either of these values", col,  app.data.Properties.VariableNames);
            end
        end
    end
    
    methods (Description= "data",Access = private) 
        
        function updateDataTable(app)
            app.visibleData.Data = app.data(:, app.visibleData.ColumnName);
        end
        
        
        function processLoadData(app, filenames, filepaths)
            mask = ismember(filenames, app.data.(app.colName('name')));        
            newData = table(filenames(~mask), filepaths(~mask), 'VariableNames', [app.colName("name"),app.colName("path")]);
            if height(newData) == 0
                return
            end
            newData.(app.colName("select")) = true(height(newData),1);
            newData.(app.colName("manual")) = false(height(newData),1);
            newData.(app.hColName("imgColor")) = repmat([0, 0, 0], height(newData), 1);
            newData.(app.colName("pivot")) = false(height(newData),1);
            newData.(app.hColName("tform")) = repmat(affinetform2d(), height(newData), 1);
            newData.(app.colName("ID")) = (height(app.data)+1 :height(app.data)+height(newData))';
            for i=1:height(app.process_load_data_table)
                
                columns = cell(1, length(app.process_load_data_table.input_cols{i}));
                for j=1:length(columns)
                    columns{j} = newData.(app.process_load_data_table.input_cols{i}{j});
                end
                handle = app.process_load_data_table.handles{i};
                newData.(app.process_load_data_table.output_cols{i}) = handle(columns{:});
            end
            
            
            for i=1:length(app.imageVersionDropDown.Items)
                col = app.imageVersionDropDown.Items{i};

                outView = @(x) affineOutputView(size(newData.(col + app.rawsuffix){x}),newData.(app.hColName("tform"))(x),"BoundsStyle","FollowOutput");
                arrayName = @(x) string(sprintf('%s [%d * %d]', class(x{:}), size(x{:},1), size(x{:},2)));
                
                newData.(col + app.viewsuffix) = arrayfun(outView, 1:height(newData))';
                newData.(col) = arrayfun(arrayName, newData.(col + app.rawsuffix));
            end
            
            toInit = setdiff(app.data.Properties.VariableNames, newData.Properties.VariableNames);
            for i=1:length(toInit)
                newData.(toInit{i}) = cell(height(newData), 1);
            end
            
            reorderedTable = newData(:, app.data.Properties.VariableNames);
            app.data = [app.data; reorderedTable];
            
            updateDataTable(app);
            app.resetSelMem();
        end
        
        
        function updateTForms(app, tforms, selectIDx)
            % just reasigns the transform for each selectIDx to the new one
            % also multiplies the new one with the old one in order to
            % preserve current registration; on the first iteration the
            % current tform is the identity matrix so the multiplication
            % results in the new one alone.
            if ~(length(tforms) == length(selectIDx))
                error("BUG: length of selected images does not equal length of returned registered transforms")
            end
            
            for i=1:length(selectIDx)
                newTform = affinetform2d(tforms(i).A * app.data.(app.hColName("tform"))(selectIDx(i)).A);
                app.data.(app.hColName("tform"))(selectIDx(i)) = newTform;
                % Recalculate the world limits/ imref2d view for this transformation
                for j=1:length(app.imageVersionDropDown.Items)
                    col = app.imageVersionDropDown.Items{j};
                    app.data.(col + app.viewsuffix)(selectIDx(i)) = affineOutputView(size(app.data.(col + app.rawsuffix){selectIDx(i)}),...
                                                                                    newTform, "BoundsStyle", "FollowOutput");
                end
            end
        end
        
        
        function ApplyCtrlPts(app)
            % Apply the control points if possible
            [movingPoints, fixedPoints, nmoving, ~, movingPointsidx, ~, idx] = SelectCtrlPts(app);
            
            if ~ismember(nmoving, [0, 1, 2])
                % Calculate the transform
                if nmoving==1
                    % Only translate
                else
                    tformType = app.config.register.transform;
                    tforms = fitgeotform2d([movingPoints.x, movingPoints.y], [fixedPoints.x, fixedPoints.y], tformType);
                    % Update the existing image transforms
                    [x, y] = transformPointsForward(tforms, movingPoints.x, movingPoints.y);
                    app.ctrlPts.movingPoints(movingPointsidx,:) = table(x,y,movingPoints.id);
                    tforms = repmat(tforms, [length(idx), 1]);
                    updateTForms(app, tforms, idx)
                    % Update the moving points to stay on now moved image
                end
                
                imgLabel = app.imageVersionDropDown.Value;
                app.outputView = getLargestImgRef(app.data.(imgLabel + app.viewsuffix)(:));
                
                % col = app.imageVersionDropDown.Value;
                % outView = col + app.viewsuffix;
                
                warpImgs(app, imgLabel, app.outputView)
                app.plotImg();
            end
        end
        
        
        function resetID(app)
            IDs = 1:height(app.data);
            app.data.(app.colName("ID")) = IDs';
            app.updateDataTable();
        end
        
        
        function resetSelMem(app)
            app.selMemory.setLen(0);
            curSel = find(app.data.(app.colName("select")));
            app.selMemory.lengthen(curSel);
        end
    end
    
    
    methods (Description= "Image",Access = private)
        
        function resetImgColor(app, selectedImgs)
            % Recalculate colors to use to maximize contrast
            n = length(find(selectedImgs));
            
            if n==0
                colors = 0.7;
            elseif n==1
                colors = [0 1 1];
            elseif n==2
                colors = [0 1 1;...
                          1 0 0];
            elseif n==3
                colors = [0 0 1;...
                          0 1 0;...
                          1 0 0];
            else
                colors = flipud(cmapL([0 0 1; 0 1 1; 0 1 0; 1 0.7 0; 1 0 0; 0.7 0 1], n));
            end
            if strcmp(app.ColorSortingSwitch.Value, 'Color retain')
                % Use queue values as index to assign color
                selectedImgs = app.selMemory.vals;
                selectedImgs(selectedImgs==0) = [];
            end
            app.data.(app.hColName("imgColor"))(:,:) = 0.7;
            app.data.(app.hColName("imgColor"))(selectedImgs, :) = colors;
        end
        
        
        function updateSelectMem(app)
            vals = app.selMemory.setLen(app.UseNRecSpinner.Value)
            if vals
                for i=1:length(vals)
                    app.data.(app.colName("select"))(vals(i)) = 0;
                end
            end
        end
        
        
        function updateImageTable(app)
            % update color of the cells
            app.imageTable.Data = app.data(:, app.imageTable.ColumnName);
            removeStyle(app.imageTable)
            for i=1:height(app.data)
                style = uistyle('BackgroundColor',app.data.(app.hColName("imgColor"))(i, :));
                if app.data.(app.colName("manual"))(i)
                    addStyle(app.imageTable,style,'cell',[i 1; i 2; i 3])
                else
                    addStyle(app.imageTable,style,'cell',[i 1; i 2])
                    style = uistyle('BackgroundColor', [0.7 0.7 0.7]);
                    addStyle(app.imageTable,style,'cell',[i, 3])
                end
            end
        end
        
        
        function warpImgs(app, imageName, outputView)
            imgLabel = imageName + app.rawsuffix;
            
            for i = 1:height(app.data)
                app.data.(app.hColName("wImg")){i} = imwarp(app.data.(imgLabel){i}, app.data.(app.hColName("tform"))(i),...
                                                        "OutputView", outputView,...
                                                        "interp", "nearest", ...
                                                        "FillValues", NaN);
            end
        end
        
        
        function plotImg(app)
           imgID = app.data.(app.colName("select"));
           if sum(imgID) == 0
               finalImg = zeros(app.outputView.ImageSize);
               x = [0 app.outputView.ImageSize(2)];
               y = [0 app.outputView.ImageSize(1)];
           else
               selectedImgs = app.data.(app.hColName("wImg"))(imgID);
               colors = app.data.(app.hColName("imgColor"))(imgID, :);
               finalImg  = CreateRGB2(selectedImgs, colors);
               x = app.outputView.XWorldLimits;
               y = app.outputView.YWorldLimits;
           end
           
           set(app.hmainImg, 'CData', finalImg, 'XData', x, 'YData', y);
        end
        
        
        function setImg(app)
            warpImgs(app, app.imageVersionDropDown.Value, app.outputView);
            plotImg(app)
            FullView(app)
        end
        
        
        function FullView(app)
            if app.FullviewCheckBox.Value
                xlim(app.UIAxes, app.outputView.XWorldLimits)
                ylim(app.UIAxes, app.outputView.YWorldLimits)
            end
        end
        
        
        function calcImageCorrelation(app)
            if ~isempty(app.data.(app.hColName("wImg")){1})
                corrScore = BImgOverlapScore(app.data.(app.hColName("wImg")));
            else
                corrScore = BImgOverlapScore(app.data.(app.hColName("Image_raw")));
            end
            imagesc(app.UIAxes_corr, corrScore)
            set(app.UIAxes_corr, "YTick", 1:size(corrScore, 1), "YTickLabel", app.data.("IDName"),...
                                 "XTick", 1:size(corrScore, 2))
            ylim(app.UIAxes_corr, [0.5, size(corrScore, 1)+0.5])
            xlim(app.UIAxes_corr, [0.5, size(corrScore, 2)+0.5])
            
            idx = true(size(corrScore));
            idx(triu(idx)) = false;
            
            maxVali = max(corrScore(idx));
            maxVal = max(maxVali, app.corr_maxVal, 'omitmissing');
            if maxVal ~= app.corr_maxVal
                app.corr_maxVal = maxVal;
            end
            app.Label_corr.Text = {sprintf('min correlation: %.2f', min(corrScore(idx)));...
                                   sprintf('max correlation: %.2f max ever: %.2f', maxVali, app.corr_maxVal)};
        end
        
        
        function DrawCtrlPts(app)
            [movingPoints, fixedPoints, ~, nfixed, ~, ~, ~] = SelectCtrlPts(app);

            app.ctrlPts.h = gobjects(3,1);
            set(app.ctrlPts.hmoving, 'XData', movingPoints.x, 'YData', movingPoints.y)    
            set(app.ctrlPts.hfixed, 'XData', fixedPoints.x, 'YData', fixedPoints.y)
            % Make multiple lines with a vector by putting nan inbetween line segments
            xL = cat(2, [movingPoints.x(1:nfixed,:) fixedPoints.x], nan(nfixed, 1))';
            xL = xL(:);
            yL = cat(2, [movingPoints.y(1:nfixed,:) fixedPoints.y], nan(nfixed, 1))';
            yL = yL(:);
            set(app.ctrlPts.hlines, 'XData', xL, 'YData', yL)
        end
        
        
        function [movingPoints, fixedPoints, nmoving, nfixed, movingPointsidx, fixedPointsidx, idxMove] = SelectCtrlPts(app)
            idxMove = find(app.data.(app.colName("manual")));
            movingPoints = app.ctrlPts.movingPoints;
            movingPointsidx = ismember(movingPoints.id, idxMove);
            movingPoints = movingPoints(movingPointsidx, :);
            nmoving = size(movingPoints, 1);

            fixedPoints = app.ctrlPts.fixedPoints;
            fixedPointsidx = ismember(fixedPoints.id, idxMove);
            fixedPoints = fixedPoints(fixedPointsidx, :);
            nfixed = size(fixedPoints, 1);
        end
        
        
        function UpdateRoiLinks(app)
            % Linking overlapping ROIs between all recording pairs above threshold,
            app.linkROIs.thres = app.ChronicThresholdSlider.Value/100;
            app.linkROIs.linked = LinkOverlappingRois(app.linkROIs.inRoi, app.linkROIs.thres);
            % Squishing linked ROIs into one link matrix
            app.linkROIs.linkMat = SquishLinkedRois(app.linkROIs.linked);
        end
        
        
        function DrawLinkRoisMainImg(app)
            nfiles = app.linkROIs.nfiles;
            
            % Draw main image
            if strcmp(app.ChronicViewDropDown.Value, 'number of links')
                colorsImg = hot(height(app.data));
                colorsImg = [0.5 0.5 0.5; colorsImg(1:end-1,:)];
                rgb = CreateRGB2(app.linkROIs.nLinksMask, colorsImg);
                
                cx = 1:nfiles;
                set(app.UIAxesColorbar, 'XTick', cx)
                % xlabel(app.UIAxesColorbar, 'Number of links')
            elseif strcmp(app.ChronicViewDropDown.Value, 'match ID')
                nrois = cat(1, app.linkROIs.PPs.Cnt);
                nmatches = size(app.linkROIs.linkMat, 1);
                idx = ChronicVal2i(app.linkROIs.linkMat, nrois);
                
                dims = size(app.linkROIs.Masks{1});                    
                colorsImg = [0.5 0.5 0.5; lines(nmatches)];
                rgb = zeros(dims);
                for i = 1:nfiles
                    idxi = idx{i}+1;
                    rgb = max(rgb, RoiVal2Img(app.linkROIs.Masks{i}, app.linkROIs.PPs(i),...
                                            idxi, [], colorsImg, 'index', false));
                end
                cx = 0:nmatches;
                set(app.UIAxesColorbar, 'XTickMode', 'auto')
                % xlabel(app.UIAxesColorbar, 'Number of links')
            elseif strcmp(app.ChronicViewDropDown.Value, 'score')
                nrois = cat(1, app.linkROIs.PPs.Cnt);
                [~, vals] = ChronicVal2i(app.linkROIs.linkMat, nrois, app.linkROIs.score, 1:nfiles);
                
                dims = size(app.linkROIs.Masks{1});
                rgb = zeros(dims);
                colorsImg = cmapL([0 1 1;  0 1 0;  1 0 0;  0.5 0 0;  0.5 0.5 0.5],...
                                      [50,     25,     50,        1]);
                for i = 1:nfiles
                    vals{i}(isnan(vals{i})) = 0;
                    rgb = max(rgb, RoiVal2Img(app.linkROIs.Masks{i}, app.linkROIs.PPs(i),...
                                            vals{i}, [], colorsImg, 'value', false));
                end
                cx = linspace(0,1,250);
                set(app.UIAxesColorbar, 'XTickMode', 'auto')
                % xlabel(app.UIAxesColorbar, 'Score')
            else
                rgb = [0 0; 1 1];
            end
            
            imagesc(app.UIAxesColorbar, cx, 1, cx)
            colormap(app.UIAxesColorbar, colorsImg)
            xlim(app.UIAxesColorbar, cx([1 end]))
            
            x = app.outputView.XWorldLimits;
            y = app.outputView.YWorldLimits;
            imagesc(app.UIAxesRoiLinks, x, y, rgb)
        end
        

        function DrawLinkRoisSup(app)
            colors =  cmapL([1 0 1; 0 0 1; 0 0 0], 256);
            nfiles = app.linkROIs.nfiles;

            % Overlap score stuff for supplemental axes 1
            xD = app.linkROIs.nLinks;
            yD = app.linkROIs.score;
            set(app.h.scoreScatter, 'XData', xD+(rand(length(yD),1)./5-0.1),...
                                    'YData', yD)
            xDM = 2:nfiles;
            yDM = zeros(nfiles-1, 1);
            for i = 2:nfiles
                yDM(i-1) = mean(yD(xD==i));
            end
            set(app.h.scoreLine, 'XData', xDM, 'YData', yDM)
            set(app.h.scoreThresLine, 'XData', [0.5 nfiles+0.5], 'YData', app.linkROIs.thres([1 1]))
            set(app.UIAxesRoiLinksSup1, 'XTick', 1:nfiles) % xlabels for this graph is set in UpdateChronicStatistics
            
            
            % Set found back matrix for supplemental axes 2
            if strcmp(app.ShowingpercentagesButton.Text, 'Showing percentages')
                vals = app.linkROIs.nFoundBackPerc;
            else
                vals = app.linkROIs.nFoundBack;
            end
            delete(app.h.foundbackText)
            app.h.foundbackText = ImageValueText(vals, 'w', 0, app.UIAxesRoiLinksSup2);
            if strcmp(app.ShowingpercentagesButton.Text, 'Showing ROI numbers')
                vals(end,1:end-1) = vals(end,1:end-1) ./ nfiles;
                vals(:,end) = vals(:,end) ./ nfiles;
                vals(end) = max(vals(1:end-1));
            end
            rgb = SetLimits(vals, [], colors);
            set(app.h.foundbackImg, 'CData', rgb)
            set(app.h.foundbackLine, 'XData', [0 nfiles nfiles]+0.5, 'YData', [nfiles nfiles 0]+0.5)
            ylabels = repmat({'Mean'}, [nfiles+1, 1]);
            for i = 1:nfiles
                ylabels{i} = sprintf('rec %d', i); % xlabels for this graph is set in UpdateChronicStatistics
            end
            set(app.UIAxesRoiLinksSup2, 'XTick', 1:(nfiles+1),...
                                        'YTick', 1:(nfiles+1), 'YTickLabels', ylabels)
            xlim([app.UIAxesRoiLinksSup1, app.UIAxesRoiLinksSup2], [0.5 nfiles+1.5])
            ylim(app.UIAxesRoiLinksSup2, [0.5 nfiles+1.5])
            
            
            % Set confusion matrix for supplemental axes 3
            if strcmp(app.ShowingpercentagesButton.Text, 'Showing percentages')
                vals = app.linkROIs.confusionFoundMatPerc;
            else
                vals = app.linkROIs.confusionFoundMat;
            end
            rgb = SetLimits(vals, [], colors);
            set(app.h.confusionImg, 'CData', rgb)
            delete(app.h.confusionText)
            bool = ~tril(true(size(vals)));
            vals(bool) = NaN;
            app.h.confusionText = ImageValueText(vals, 'w', 0, app.UIAxesRoiLinksSup3);
            set(app.UIAxesRoiLinksSup3, 'XTick', 1:nfiles, 'XTickLabels', ylabels,...
                                        'YTick', 1:nfiles, 'YTickLabels', ylabels)
            xlim(app.UIAxesRoiLinksSup3, [0.5 nfiles+0.5])
            ylim(app.UIAxesRoiLinksSup3, [0.5 nfiles+0.5])
        end
        
        
        function [xlimsNew, ylimsNew] = ZoomImage(app, x, y, xlims, ylims, verticalScrollCount)
            % new = current edge + (distance from edge * zoomfactor)
            zoomfactor = 0.1; % fraction change of FOV
            zoomfactor = 1 + (zoomfactor*verticalScrollCount);
            xlimsNew = x - ((x - xlims(1)) * zoomfactor);
            xlimsNew(2) = x + ((xlims(2) - x) * zoomfactor);
            ylimsNew = y - ((y - ylims(1)) * zoomfactor);
            ylimsNew(2) = y + ((ylims(2) - y) * zoomfactor);
            xlimsNew(xlimsNew < app.outputView.XWorldLimits(1)) = app.outputView.XWorldLimits(1);
            xlimsNew(xlimsNew > app.outputView.XWorldLimits(2)) = app.outputView.XWorldLimits(2);
            ylimsNew(ylimsNew < app.outputView.YWorldLimits(1)) = app.outputView.YWorldLimits(1);
            ylimsNew(ylimsNew > app.outputView.YWorldLimits(2)) = app.outputView.YWorldLimits(2);
        end
        
        
        function UpdateChronicStatistics(app)
            app.linkROIs.nLinks = sum(app.linkROIs.linkMat~=0, 2);
            doPlot = false;
            app.linkROIs.score = OverlapScoreRun(app.linkROIs.linkMat, app.linkROIs.inRoi, app.linkROIs.nLinks, NaN, doPlot);
            
            nrois = cat(2, app.linkROIs(:).PPs.Cnt);
            linkMatAllRois = ExtendLinkMat(app.linkROIs.linkMat, nrois);
            nLinksAllRois = sum(linkMatAllRois~=0, 2); % number of roi links in each row
            app.linkROIs.nLinksMask = CreateNLinksMask(app.linkROIs.Masks, linkMatAllRois, nLinksAllRois);
            
            [app.linkROIs.nFoundBack, app.linkROIs.nFoundBackPerc, nameCol] = CalcFoundBack(linkMatAllRois, nLinksAllRois, nrois, [], doPlot);
            set([app.UIAxesRoiLinksSup2, app.UIAxesRoiLinksSup1], 'XTickLabel', [nameCol, {'Mean'}])
            [app.linkROIs.confusionFoundMat, app.linkROIs.confusionFoundMatPerc] = CalcConfusionFoundMat(linkMatAllRois, nrois);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           if ~ispref('imageReg2P', 'config_name')
                app.config = resetConfig();
            else
                config_name = getpref('imageReg2P', 'config_name');
                app.config = getpref('imageReg2P', config_name);
            end
            
            app.initData();
            app.selMemory = basicQueue(); %memory of order in which images are selected
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            
            set(app.UIAxesScrollManual, 'YDir', 'reverse')
            xlim(app.UIAxesScrollManual, [0 3])
            ylim(app.UIAxesScrollManual, [0 3])
        end

        % Button pushed function: LoadfolderButton
        function LoadDataDir(app, event)
            strSearch = app.config.fileType;
            removeDuplicates = app.config.fileRemoveDuplicates;
            prompt = ['What should the folder search depth be? ',...
                      '0=selected folder itself, 1=folders inside selected folder, etc'];
            if app.config.folderAskDepth
                depth = inputdlg(prompt, 'search depth', [1 52], "0");
                depth = str2double(depth);
            else
                depth = 0;
            end
            strFolder = uigetdir();
            [filepaths, filenames] = CollectFiles(strSearch, strFolder, depth, removeDuplicates);
            if ~isequal(length(filenames), 0)
                processLoadData(app, filenames, filepaths);
            end
            figure(app.UIFigure) % refocus on figure
        end

        % Button pushed function: LoadfilesButton
        function LoadDataFile(app, event)
            strSearch = ['*' app.config.fileType];
            [filenames, filepaths] = fetchFile(strSearch);
            if ~ isequal(length(filenames), 0)
                processLoadData(app, filenames, filepaths);
            end
            figure(app.UIFigure) % refocus on figure
        end

        % Button pushed function: RemoveselectedrowsButton
        function removeSelectedRows(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title,'Image')
                name = 'imageTable';
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Data')
                name =  'visibleData';
            end
            
            if ~ strcmp(app.(name).SelectionType, 'row')
                error("Selection type is not row");
            end

            app.data(app.(name).Selection, :) = [];
            app.selMemory.bulkRemoveAndReassign(app.(name).Selection);
            app.(name).Selection = [];

            app.resetID(); %no need to updateDataTab as resetID does this

            if strcmp(app.TabGroup.SelectedTab.Title,'Image')
                app.updateImageTable();
                if height(app.data) >= 1
                    app.UseNRecSpinner.Limits = [1 height(app.data)];
                end
                plotImg(app);
                
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Data')
                app.updateDataTable();
            end
        end

        % Button pushed function: SetapppreferencesButton
        function SetapppreferencesButtonPushed(app, event)
            clc
            fprintf('These are all the preferences your MATLAB has:\n')
            fprintf('prefs = getpref')
            prefs = getpref
            fprintf('\n\nimageReg2Pprefs = prefs.imageReg2P')
            imageReg2Pprefs = prefs.imageReg2P
            fprintf('\n\nconfigDataLoaded = app.config')
            configDataLoaded = app.config
            fprintf(['\n\nApply your config file by editing the settings in createConfig',...
                '\nand running, for example: \ncreateConfig("myNew_config")\n'])
            assignin('base', 'prefs', prefs)
            assignin('base', 'imageReg2Pprefs', imageReg2Pprefs)
            assignin('base',  'configDataLoaded', configDataLoaded)
            edit createConfig.m
        end

        % Button pushed function: ChoosepreferenceButton
        function ChoosepreferenceButtonPushed(app, event)
            if ispref('imageReg2P', 'config_name')
                configs = getpref('imageReg2P');
                currentConfig = configs.config_name;
            else
                currentConfig = '';
            end

            ChooseConfig

            if ispref('imageReg2P', 'config_name')
                newConfigs = getpref('imageReg2P');
                newConfig = newConfigs.config_name;
            else
                newConfig = '';
            end
            
            if isempty(newConfig)            
                msgbox('Set a valid config')
            elseif strcmp(currentConfig, newConfig)
                msgbox('Selected the same config, nothing changed')
            else
                msgbox(sprintf('Restart app to apply change from config  %s  to  %s',...
                                currentConfig, newConfig))
            end
        end

        % Cell edit callback: visibleData
        function dataPivot(app, event)
            select = app.colName("select");
            pivot = app.colName("pivot");
            row = event.Indices(1);
            col = event.Indices(2);
            prevRow = app.previousPivotIndex;
            prevSel = app.prevSelect;
            
            if app.visibleData.ColumnName(col) == pivot
                if event.NewData == 1
                    %turn previous pivot false if it is true
                    if prevRow == row
                        return
                    end
                    if prevRow
                        app.data.(pivot)(prevRow) = 0;
                        if prevSel
                            app.data.(select)(prevRow) = 1;
                        else
                            app.selMemory.remove(prevRow);
                        end
                    end
                    
                    %turn current select false if it is true
                    app.previousPivotIndex = row;
                    if app.data.(select)(row)
                        app.data.(select)(row) = 0;
                        app.prevSelect = 1;
                    else
                        app.prevSelect = 0;
                        app.selMemory.enqueue(row);
                    end
                else
                    %remove prev pivot memory and return select value to
                    %what it was
                    app.previousPivotIndex = 0;
                    if prevSel
                        app.data.(select)(row) = 1;
                        app.prevSelect = 0;
                    else
                        app.selMemory.remove(row);
                    end
                    
                    %dont need to check for case that prevrow = row, as by
                    %definition for the input to be 0 and the pivot, it
                    %mustve been the only true value in that column before
                end
                app.data.(pivot)(row) = event.NewData;
                
            elseif app.visibleData.ColumnName(col) == select
                app.data.(select)(row) = event.NewData;
                if event.NewData
                    if row == app.previousPivotIndex
                        app.data.(pivot)(row) = 0;
                        app.previousPivotIndex = 0;
                    else
                        app.selMemory.enqueue(row);
                    end
                else
                    app.selMemory.remove(row);
                end
            end
            updateDataTable(app);
        end

        % Button down function: DataTab
        function updateDataTab(app, event)
            %update table
            idx = find(app.data.(app.colName("pivot")));
            if idx
                if app.data.(app.colName("select"))(idx) == 1
                    app.data.(app.colName("select"))(idx) = 0;
                else
                    app.selMemory.enqueue(idx);
                end
            end
            
            updateDataTable(app);
        end

        % Button down function: ImageTab
        function updateImageTab(app, event)

            if ~app.ChronicReloadandRecalculatewhenswitchingtabsCheckBox.Value
                app.UIAxes.XLim = app.UIAxesRoiLinks.XLim;
                app.UIAxes.YLim = app.UIAxesRoiLinks.YLim;
            end
            
            idx = find(app.data.(app.colName("pivot")));
            if idx
                app.data.(app.colName("select"))(idx) = 1;
            end
            
            %set bottom limit, otherwise clicking on select throws an error
            %when enqueueing on a queue of size 0
            if height(app.data) >= 1
                app.UseNRecSpinner.Limits = [1 height(app.data)];
            end
            app.UseNRecSpinner.Value = max([sum(app.data.(app.colName("select")))], app.UseNRecSpinner.Limits(1));
            
            
            vals = app.selMemory.setLen(max([sum(app.data.(app.colName("select"))),1]));
            if vals
                error("there was an error with the select memory, values were discarded which means a bug")
            end
            app.UseallCheckBox.Value = false;
            
            %load in image viewer uitable
            if height(app.data) >= 1
                resetImgColor(app, app.data.(app.colName("select")));
                updateImageTable(app);
                
                %plot images                    
                imgLabel = app.imageVersionDropDown.Value + app.viewsuffix;
                app.outputView = getLargestImgRef(app.data.(imgLabel)(:));

                setImg(app);
                calcImageCorrelation(app);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            idx = find(app.data.(app.colName("manual")));
            if ~isempty(idx) % && strcmp(app.TabGroup2.SelectedTab.Title, 'Ctrl pts')
                [~, ~, nmoving, nfixed, movingPointsidx, fixedPointsidx, ~] = SelectCtrlPts(app);
                
                if event.Button==1
                    % add point
                    if nfixed == nmoving
                        % Add moving point
                        app.ctrlPts.movingPoints(end+1, :) = num2cell([event.IntersectionPoint(1, [1 2]), idx(1)]);
                        app.ctrlPts.nmoving = app.ctrlPts.nmoving + 1;
                    else
                        % Add fixed point
                        app.ctrlPts.fixedPoints(end+1, :) = num2cell([event.IntersectionPoint(1, [1 2]), idx(1)]);
                        app.ctrlPts.nfixed = app.ctrlPts.nfixed + 1;
                        
                        ApplyCtrlPts(app)
                    end
                    DrawCtrlPts(app)
                else % Remove latest reference/ control point that belongs to the currently editing manual moving recordings
                    if nmoving > nfixed && nmoving>0
                        movingPointsidx = find(movingPointsidx);
                        app.ctrlPts.movingPoints(movingPointsidx(end),:) = [];
                        app.ctrlPts.movingPoints
                        app.ctrlPts.nmoving = app.ctrlPts.nmoving - 1;
                        app.ctrlPts.nmoving
                        DrawCtrlPts(app)
                    elseif nfixed>0
                        fixedPointsidx = find(fixedPointsidx);
                        app.ctrlPts.fixedPoints(fixedPointsidx(end),:) = [];
                        app.ctrlPts.nfixed = app.ctrlPts.nfixed - 1;
                        DrawCtrlPts(app)
                    end
                end
            end
            clc
            fprintf('%dfixed %dmoving points\n', app.ctrlPts.nfixed, app.ctrlPts.nmoving)
            app.ctrlPts.movingPoints
            app.ctrlPts.fixedPoints
        end

        % Button pushed function: RegisterimagesButton
        function runRegistration(app, event)
           progressBar = waitbar(0, "Initialising ...", 'Name', 'Closing this cancels the registration');
           %get info on selected images, and image type
           imgLabel = app.imageVersionDropDown.Value;
           selectID = find(app.data.(app.colName("select")));
           pivotID = find(app.data.(app.colName("pivot"))(selectID));
           
           outView = getLargestImgRef(app.data.(imgLabel + app.viewsuffix)(selectID));
            %{
           To enable continuous refinement we need to let successive
           registrations build upon already found solutions
           Therefore:
                apply the current transformation to the
                images before sending to registerImgs, then
                multiply the output best found tform to the
                current existing tforms, for the first iteration this is
                overhead but has no impact as current tform are identity
                matrices
           %}
           warpImgs(app, imgLabel, outView);
           selectedImgs = app.data.(app.hColName("wImg"))(selectID);
           
           regInfo = app.config.register;
           %run optimisation
           transforms = registerImgs(selectedImgs, pivotID, ...
               regInfo.metric, regInfo.optimiser, levels = regInfo.levels, ...
               transform = regInfo.transform, crossCTransform= regInfo.crossCTransform, ...
               progressBar=progressBar);
           waitbar(100, progressBar, "Finishing ..."); 
           
           %populate the table with the transforms
           updateTForms(app, transforms, selectID);
           close(progressBar)
           app.outputView = getLargestImgRef(app.data.(imgLabel + app.viewsuffix)(:));
           setImg(app);
           calcImageCorrelation(app);
        end

        % Button pushed function: ResettransformsButton
        function resetTforms(app, event)
            selectID = app.data.(app.colName("select"));
            pivotID = app.data.(app.colName("pivot"));
            manualID = app.data.(app.colName("manual"));
            if any(manualID)
                idx = find(manualID);
                RemovecontrolpointsButtonPushed(app, [])
            else
                idx = find((selectID | pivotID));
            end
            for i=1:length(idx)
                app.data.(app.hColName("tform"))(idx(i)) = affinetform2d();
                % Recalculate the world limits/ imref2d view for this transformation
                for j=1:length(app.imageVersionDropDown.Items)
                    col = app.imageVersionDropDown.Items{j};
                    app.data.(col + app.viewsuffix)(idx(i)) = affineOutputView(size(app.data.(col + app.rawsuffix){idx(i)}),...
                                                                                    app.data.(app.hColName("tform"))(idx(i)), "BoundsStyle", "FollowOutput");
                end
            end
            imgLabel = app.imageVersionDropDown.Value + app.viewsuffix;
            app.outputView = getLargestImgRef(app.data.(imgLabel)(:));
            setImg(app)
            calcImageCorrelation(app)
            if strcmp(app.TabGroup2.SelectedTab.Title, 'Manual')
                UpdateManualMoveTab(app, [])
            end
        end

        % Cell edit callback: imageTable
        function imageSelect(app, event)
            
            % Create fake event to change pivot
            col = app.colName("pivot");
            test.Indices(1) = event.Indices(1);
            test.Indices(2) = find(strcmp(app.visibleData.ColumnName, col));
            test.NewData = event.NewData;
            
            if event.Indices(2) == 1 % Change pivot
                app.dataPivot(test)
                if strcmp(app.TabGroup.SelectedTab.Title,'Image')
                     idx = find(app.data.(app.colName("pivot")));      
                     app.data.(app.colName("select"))(idx) = true;
                     if app.ResetcolorsCheckBox.Value
                         resetColor(app, [])
                     end
                     app.updateImageTable();
                     app.plotImg();
                end
                
            elseif event.Indices(2) == 2 % Change displayed recordings
                select = app.colName("select");
                row = event.Indices(1);
                app.data.(select)(row) = event.NewData;
                if event.NewData % Adding displayed recording
                    if strcmp(app.RemovingOldestSwitch.Value, 'Removing newest')
                        removeSide = 'dequeue';
                    else % remove oldest
                        removeSide = 'pop';
                    end
                    val = app.selMemory.enqueue(row, removeSide);
                    if val
                        app.data.(select)(val) = false;
                    end
                else % Removing displayed recording and if this is pivot, remove pivot
                    if app.data.Pivot
                        app.dataPivot(test)
                    end
                    try
                        app.selMemory.remove(row);
                    catch ME
                        disp("TRY CATCH BLOCK:")
                        disp(row)
                        disp(event.NewData)
                        temp = app.selMemory.getState();
                        disp(temp)
                        rethrow(ME)
                    end
                end
                if app.ResetcolorsCheckBox.Value
                    resetColor(app, [])
                end
                app.selMemory
                updateImageTable(app);
                plotImg(app);

            elseif event.Indices(2) == 3 % Change manually moving recording selection
                app.data.(app.colName("manual")) = event.Source.Data.(app.colName("manual"));
                updateImageTable(app)
                UpdateManualMoveTab(app, [])
                DrawCtrlPts(app)
            else
                error("Clicked column according to event.Indices(2), is not supposed to be editable")
            end
        end

        % Value changed function: ColorSortingSwitch, 
        % ...and 2 other components
        function resetColor(app, event)
            selection = app.data.(app.colName("select"));
            resetImgColor(app, selection);
            updateImageTable(app);
            plotImg(app);
            % set(app.UIAxesScrollManual, 'CameraPosition')
        end

        % Value changed function: UseallCheckBox
        function UseallCheckBoxValueChanged(app, event)
            if event.Value == 1
                app.selMemState = app.selMemory.getState();
                newIDX = setdiff((1:height(app.data)), app.selMemory.vals);
                app.data.(app.colName("select"))(newIDX) = 1;
                app.selMemory.lengthen(newIDX);
                
                app.UseNRecSpinner.Value = app.UseNRecSpinner.Limits(2);
            else
                app.selMemory.loadState(app.selMemState);
                newIDX = setdiff((1:height(app.data)), app.selMemory.vals);
                app.data.(app.colName("select"))(newIDX) = 0;
                app.UseNRecSpinner.Value = app.selMemState.size;
            end
            
            if app.ResetcolorsCheckBox.Value
                resetColor(app, [])
            end
            updateImageTable(app);
            plotImg(app);
        end

        % Value changed function: UseNRecSpinner
        function UseNRecSpinnerValueChanged(app, event)
            % Spinner to select number of files to show
            if event.PreviousValue == app.UseNRecSpinner.Limits(2) && event.Value < app.UseNRecSpinner.Limits(2)
                app.UseallCheckBox.Value = false;
            end
            updateSelectMem(app);
            if app.ResetcolorsCheckBox.Value
                resetColor(app, [])
            end
            updateImageTable(app);
            plotImg(app)
        end

        % Value changed function: imageVersionDropDown
        function dropDownImgRefresh(app, event)
            setImg(app)
        end

        % Window scroll wheel function: UIFigure
        function ScrollChangeValue(app, event)
            clc
            % verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            if strcmp(app.TabGroup.SelectedTab.Title,'Image')
                
                % pos = app.UITableTransform.Position;
                % set(app.UITableTransform, 'Position', [0, pos(2), 1, pos(4)]);
                
                idx = find(app.data.(app.colName("manual")));
                n = size(app.data.(app.hColName("tform"))(1).A);
                curS = app.UIAxesScrollManual.CurrentPoint(1, [1 2]);
                curS = ceil(curS);
                curM = round(app.UIAxes.CurrentPoint(1, [1 2]));
                if strcmp(app.TabGroup2.SelectedTab.Title, 'Manual') && all(curS>0) && (curS(1)<=n(2) && curS(2)<n(1)) && ~isempty(idx)
                    % Scrolling on the manual transform matrix to edit the transform
                    x = curS(1);
                    y = curS(2);
                    % fprintf('ya%d, %d. scrollCount%d, amount%d\n', cur(1), cur(2), verticalScrollCount, verticalScrollAmount)
                    speedofChange = [0.005, 0.005, 1;...
                                     -0.005, 0.005, -1;...
                                     0,   0,   0];
                    speedofChange = speedofChange .* app.SpeedofchangeviascrollSlider.Value;
                    change = speedofChange(y,x) * -verticalScrollCount;
                    for i = idx'
                        app.data.(app.hColName("tform"))(i).A(y,x) = app.data.(app.hColName("tform"))(i).A(y,x) + change;
                        % Recalculate the world limits/ imref2d view for this transformation
                        for j=1:length(app.imageVersionDropDown.Items)
                            col = app.imageVersionDropDown.Items{j};
                            app.data.(col + app.viewsuffix)(i) = affineOutputView(size(app.data.(col + app.rawsuffix){i}),...
                                                                                  app.data.(app.hColName("tform"))(i), "BoundsStyle", "FollowOutput");
                        end
                    end
                    app.UITableTransform.Data = app.data.(app.hColName("tform"))(idx(1)).A;
                    
                    imgLabel = app.imageVersionDropDown.Value + app.viewsuffix;
                    app.outputView = getLargestImgRef(app.data.(imgLabel)(:));
                    setImg(app)
                    calcImageCorrelation(app);
                elseif curM(1)>=app.UIAxes.XLim(1) && curM(1)<=app.UIAxes.XLim(2)...
                        && curM(2)>=app.UIAxes.YLim(1) && curM(2)<=app.UIAxes.YLim(2)
                    % Scroll happened on main image, zoom image to cursor
                    fprintf('scrolled on main image, %d, %d\n', curM(1), curM(2))
                    xlims = app.UIAxes.XLim;
                    ylims = app.UIAxes.YLim;
                    x = curM(1);
                    y = curM(2);
                    [xlimsNew, ylimsNew] = ZoomImage(app, x, y, xlims, ylims, verticalScrollCount);
                    set(app.UIAxes, 'XLim', xlimsNew, 'YLim', ylimsNew)
                end
            elseif strcmp(app.TabGroup.SelectedTab.Title, 'link ROIs')
                curM = round(app.UIAxesRoiLinks.CurrentPoint(1, [1 2]));
                if curM(1)>=app.UIAxesRoiLinks.XLim(1) && curM(1)<=app.UIAxesRoiLinks.XLim(2)...
                        && curM(2)>=app.UIAxesRoiLinks.YLim(1) && curM(2)<=app.UIAxesRoiLinks.YLim(2)
                    % Scroll on main image of linked ROIs tab, zoom image to cursor
                    fprintf('scrolled on main link ROI image, %d, %d\n', curM(1), curM(2))
                    xlims = app.UIAxesRoiLinks.XLim;
                    ylims = app.UIAxesRoiLinks.YLim;
                    x = curM(1);
                    y = curM(2);
                    [xlimsNew, ylimsNew] = ZoomImage(app, x, y, xlims, ylims, verticalScrollCount);
                    set(app.UIAxesRoiLinks, 'XLim', xlimsNew, 'YLim', ylimsNew)
                end
            end
        end

        % Button down function: ManualTab
        function UpdateManualMoveTab(app, event)
            % Executes when the Manual registration tab is opened, to show
            % the data of the 1st manual moving image and scale the table
            toMove = app.data.(app.colName("manual"));
            if any(toMove)
                clc
                idx = find(toMove);
                app.UITableTransform.Data = app.data.(app.hColName("tform"))(idx(1)).A;
                app.UITableTransform.ColumnName = {'x', 'y', 'z'};
            else
                app.UITableTransform.Data = {'Select'; 'manual'; 'recording'};
            end
            % Fit the content of the table so the scrolling callback works
            % correctly. Has to fit with UIAxesScrollManual axis
            set(app.UITableTransform, 'Units', 'pixels');
            pos = app.UITableTransform.Position;
            nCols = size(app.UITableTransform.Data, 2);
            colWidths = repmat({pos(3)/nCols}, [1 3]);
            nRows = size(app.UITableTransform.Data, 1);
            rowHeight = (pos(4)-20)/nRows/2.5;
            app.UITableTransform.ColumnWidth = colWidths;
            app.UITableTransform.FontSize = rowHeight;
            app.UITableTransform.ColumnFormat = repmat({'short'}, [1, nCols]);
            % Change the axis position to ignore uitable scrollbar and labels
            app.UIAxesScrollManual.Position = [pos(1), pos(2)+12, pos(3), pos(4)-33];
            % % Testing position of UIaxes
            % set(app.UIAxesScrollManual, 'Units', 'pixels', 'Visible', 'on');
            % set(app.UITableTransform, 'Units', 'pixels', 'Visible', 'off');
            % pause(2)
            % set(app.UITableTransform, 'Units', 'pixels', 'Visible', 'on');
            % set(app.UIAxesScrollManual, 'Units', 'pixels', 'Visible', 'off');
        end

        % Cell edit callback: UITableTransform
        function UITableTransformCellEdit(app, event)
            % edits the transform values
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            
            idx = find(app.data.(app.colName("manual")));
            
            app.data.(app.hColName("tform"))(idx(1)).A(indices(1), indices(2)) = newData;
            % Apply the same change of value to the other manually moving images
            change = newData - oldData;
            idx2 = idx;
            idx2(1) = [];
            if ~isempty(idx2)
                for i = idx2'
                    newVal = app.data.(app.hColName("tform"))(i).A(indices(1), indices(2)) + change;
                    app.data.(app.hColName("tform"))(i).A(indices(1), indices(2)) = newVal;
                end
            end
            for i = idx'
                for j=1:length(app.imageVersionDropDown.Items)
                    col = app.imageVersionDropDown.Items{j};
                    app.data.(col + app.viewsuffix)(i) = affineOutputView(size(app.data.(col + app.rawsuffix){i}),...
                        app.data.(app.hColName("tform"))(i), "BoundsStyle", "FollowOutput");
                end
            end
            
            imgLabel = app.imageVersionDropDown.Value;
            app.outputView = getLargestImgRef(app.data.(imgLabel + app.viewsuffix)(:));
            setImg(app)
            calcImageCorrelation(app);
        end

        % Value changed function: SpeedofchangeviascrollSlider
        function SpeedofchangeviascrollSliderValueChanged(app, event)
            % For setting the speed of change for manual registration
            value = app.SpeedofchangeviascrollSlider.Value;
            app.SpeedofchangeviascrollSlider.Value = round(value);
        end

        % Button pushed function: RemovecontrolpointsButton
        function RemovecontrolpointsButtonPushed(app, event)
            % Remove only the control points relating to currently active
            % manual moving recordings
            [~, ~, ~, ~, movingPointsidx, fixedPointsidx, ~] = SelectCtrlPts(app);
            app.ctrlPts.movingPoints(movingPointsidx, :) = [];
            app.ctrlPts.fixedPoints(fixedPointsidx, :) = [];
            app.ctrlPts.nmoving = app.ctrlPts.nmoving - sum(movingPointsidx);
            app.ctrlPts.nfixed  = app.ctrlPts.nfixed - sum(fixedPointsidx);
            DrawCtrlPts(app)
        end

        % Button pushed function: UndolastButton
        function UndolastButtonPushed(app, event)
            % Either remove the latest moving point if it does not have a
            % fixed point (which can also be done by rightclicking)
            [~, ~, nmoving, nfixed, movingPointsidx, fixedPointsidx, ~] = SelectCtrlPts(app);
            movingPointsidx = find(movingPointsidx);
            fixedPointsidx = find(fixedPointsidx);
            if nfixed>0
                app.ctrlPts.movingPoints(movingPointsidx(end),:) = [];
                app.ctrlPts.nmoving = app.ctrlPts.nmoving -1 ;
                if nmoving == nfixed % if both were present, remove both
                    app.ctrlPts.fixedPoints(fixedPointsidx(end),:) = [];
                    app.ctrlPts.nfixed = app.ctrlPts.nfixed -1;
                end                
                ApplyCtrlPts(app)
                DrawCtrlPts(app)
            end
        end

        % Button pushed function: SavetoworkspaceButton, 
        % ...and 1 other component
        function saveRegistration_data(app, event)
            % hardcoded to also output Mask
            outputTable = app.data;
            finalView = [];
            
            if height(app.data)>0
                if ismember('Image', outputTable.Properties.VariableNames)
                    outputTable.('Image' + app.viewsuffix) = [];
                end
                if isKey(app.colName, "Image")
                    outputTable.('Image') = [];
                    [outputTable.("Image_warped"), finalView] = warpImagesfromTforms(outputTable.tForm, app.data.("Image" + app.rawsuffix));
                end
                if ismember('warpedImg', outputTable.Properties.VariableNames)
                    outputTable.('warpedImg') = [];
                end
                if isKey(app.colName, "Mask")
                    outputTable.("Mask") = [];
                    outputTable.("Mask" + app.viewsuffix) = [];
                    outputTable.("Mask_warped") = warpImagesfromTforms(outputTable.tForm, app.data.("Mask" + app.rawsuffix));
                end
            end
            
            assignin("base", "registration_data", outputTable);
            assignin("base", "registration_view", finalView)
        end

        % Button down function: linkROIsTab
        function updateROIsTab(app, event)
            nfiles = height(app.data); 
            if nfiles>1
                if app.ChronicReloadandRecalculatewhenswitchingtabsCheckBox.Value
                    tSize = 60;
                    tx = mean(app.UIAxesRoiLinks.XLim);
                    ty = mean(app.UIAxesRoiLinks.YLim);
                    delete(app.h.foundbackText)
                    app.h.foundbackText = text(app.UIAxesRoiLinks, tx, ty, 'Loading data...',...
                        'Color', 'r', 'BackgroundColor', [0 0 0 0.75], 'FontSize', tSize, 'HorizontalAlignment','center', 'VerticalAlignment','middle');
                    drawnow
                                   
                    PPs = struct('P', [],  'A', [], 'Cnt', [], 'Con', []);
                    Masks = cell(nfiles, 1);
                    for i = 1:nfiles
                        load([app.data.Path{i}, app.data.Name{i}], 'PP', 'Mask');
                        PPs(i).A = PP.A;
                        PPs(i).Cnt = PP.Cnt;
                        PPs(i).Con = PP.Con;
                        PPs(i).P = PP.P;
                        Masks{i} = Mask;
                    end
                    % Register the masks
                    app.h.foundbackText.String = 'Registering masks...';                
                    drawnow
                    Masks = warpImagesfromTforms(app.data.(app.hColName("tform")), Masks);
                    
                    app.h.foundbackText.String = 'Registering ROI contours...';                
                    drawnow
                    offset = -app.outputView.XWorldLimits(1);
                    offset(2) = -app.outputView.YWorldLimits(1);
                    for i = 1:nfiles % redraw contours
                        PPs(i) = PPtform(PPs(i), app.data.(app.hColName("tform"))(i), offset);
                        % PPs(i) = PPfromMask(Masks{i}, [], PPs(i)); % sloooow
                    end
                    app.linkROIs(1).Masks = Masks;
                    app.linkROIs.PPs   = PPs;
                    app.linkROIs.nfiles = nfiles;
    
                    app.h.foundbackText.String = 'Calculating ROI overlap...';                
                    drawnow
                    app.linkROIs.inRoi = CalcRoiOverlap(Masks);
    
                    app.h.foundbackText.String = 'Matching ROIs...';
                    drawnow
                    UpdateRoiLinks(app)
    
                    UpdateChronicStatistics(app)
                    DrawLinkRoisMainImg(app)
                    DrawLinkRoisSup(app)
                end
                app.UIAxesRoiLinks.XLim = app.UIAxes.XLim;
                app.UIAxesRoiLinks.YLim = app.UIAxes.YLim;
            else % really need more than one file
                tSize = 40;
                tx = mean(app.UIAxesRoiLinks.XLim);
                ty = mean(app.UIAxesRoiLinks.YLim);
                delete(app.h.foundbackText)
                app.h.foundbackText = text(app.UIAxesRoiLinks, tx, ty, 'Really need more than one file for this',...
                    'Color', 'r', 'BackgroundColor', [0 0 0 0.75], 'FontSize', tSize, 'HorizontalAlignment','center', 'VerticalAlignment','middle');
            end
        end

        % Value changed function: ChronicThresholdSlider
        function ChronicThresholdSliderValueChanged(app, event)
            UpdateRoiLinks(app)
            UpdateChronicStatistics(app)
            DrawLinkRoisMainImg(app)
            DrawLinkRoisSup(app)
            app.ThresholdSliderLabel.Text = {'Threshold', sprintf('%.0f%%', app.ChronicThresholdSlider.Value)};
        end

        % Button pushed function: ChronicViewerUIButton
        function ChronicViewerUIButtonPushed(app, event)
            BImgs = app.data.(app.hColName("wImg"));
            filenamesShort = app.data.(app.colName("IDName"));
            Masks = app.linkROIs.Masks;
            ChronicViewer(BImgs, Masks, filenamesShort,...
                            app.linkROIs.linkMat,...
                            app.linkROIs.PPs,...
                            app.linkROIs.score,...
                            app.linkROIs.inRoi)
        end

        % Button pushed function: LoadChronicEditsButton
        function LoadChronicEditsButtonPushed(app, event)
            linkMat = evalin('base', 'linkMat'); % load from workspace
            noChange = false;
            if size(linkMat, 1)==size(app.linkROIs.linkMat, 1)
                if all(all(linkMat==app.linkROIs.linkMat))
                    noChange = true;
                end
            end
            if noChange
                set(app.LoadChronicEditsButton, 'Text', 'No change ',...
                                                'FontWeight', 'bold')
            else % Update data
                set(app.LoadChronicEditsButton, 'Text', 'Loaded edits ',...
                                                'FontWeight', 'bold')
                app.linkROIs.linkMat = linkMat;
                UpdateChronicStatistics(app)
                DrawLinkRoisMainImg(app)
                DrawLinkRoisSup(app)
            end
            % Reset button to original state
            try
                pause(2)
                set(app.LoadChronicEditsButton, 'Text', {'Load edits'; 'from workspace'},...
                                                'FontWeight', 'normal')
            catch
                fprintf('imageReg2P app was closed fast after pressing button')
            end
        end

        % Button pushed function: ChronicSavetoFileButton
        function ChronicSavetoFileButtonPushed(app, event)
            % Add more info that we want in the chronic file
            app.linkROIs.filepaths = {app.data.Path{:}}';
            app.linkROIs.filenames = {app.data.Name{:}}';
            app.linkROIs.filenamesShort = app.data.(app.colName("IDName"));
            app.linkROIs.filedates = app.data.Date;
            app.linkROIs.BImgs = app.data.(app.hColName("wImg"));
            app.linkROIs.transformed.transforms = app.data.(app.hColName("tform"));
            
            % Give the file a nice sorting of variables just because
            C = {'nfiles', 'filepaths', 'filenames', 'filenamesShort', 'filedates', 'transformed', 'BImgs', 'Masks', 'PPs', 'inRoi',...
                'linked', 'thres', 'linkMat', 'nLinks', 'score'...
                'nLinksMask', 'nFoundBack', 'nFoundBackPerc', 'confusionFoundMat', 'confusionFoundMatPerc'};
            chronicStruct = orderfields(app.linkROIs, C);
            
            filename = app.linkROIs.filenames{1};
            % Get filename for chronic file
            spaces = regexp(filename, '_');
            filename = [filename(1:spaces(1)), filename(spaces(3)+1:spaces(4)), 'chronic.mat'];
            [chronicname, chronicpath, ~] = uiputfile('', 'save the chronic information file', filename);
            clc
            if ischar(chronicname)
                save([chronicpath, chronicname], '-struct', 'chronicStruct')
            else
                fprintf('Cancel pressed on save button')
            end                        
            
            % 
            % save([chronicpath, chronicname], 'nfiles', 'BImgs', 'Masks', 'PPs', 'filenames', 'filenamesShort', 'filepaths', 'filedates',...
            %     'transformed', 'thres', 'inRoi', 'linked', 'linkMat', 'score', 'nLinks', 'nLinksMask',...
            %     'nFoundBack','nFoundBackPerc', 'confusionFoundMat')
        end

        % Value changed function: ChronicViewDropDown
        function ChronicViewDropDownValueChanged(app, event)
            DrawLinkRoisMainImg(app)
        end

        % Button pushed function: ShowingpercentagesButton
        function ShowingpercentagesButtonPushed(app, event)
            if strcmp(app.ShowingpercentagesButton.Text, 'Showing percentages')
                app.ShowingpercentagesButton.Text = 'Showing ROI numbers';
                title(app.UIAxesRoiLinksSup2, 'Number of ROIs with n links')
                title(app.UIAxesRoiLinksSup3,'ROIs matched')
            else
                app.ShowingpercentagesButton.Text = 'Showing percentages';
                title(app.UIAxesRoiLinksSup2, 'ROI % with n links')
                title(app.UIAxesRoiLinksSup3, 'ROI % matched')
            end
            DrawLinkRoisSup(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ScrollChangeValue, true);
            app.GridLayout9 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.DataTab.ButtonDownFcn = createCallbackFcn(app, @updateDataTab, true);
            app.dataGrid = uigridlayout(app.DataTab);
            app.visibleData = uitable(app.dataGrid);
            app.visibleData.ColumnName = '';
            app.visibleData.ColumnRearrangeable = 'on';
            app.visibleData.RowName = {};
            app.visibleData.SelectionType = 'row';
            app.visibleData.CellEditCallback = createCallbackFcn(app, @dataPivot, true);
            app.dataButtonGrid = uigridlayout(app.dataGrid);
            app.SavetoworkspaceButton = uibutton(app.dataButtonGrid, 'push');
            app.SavetoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @saveRegistration_data, true);
            app.SavetoworkspaceButton.Tooltip = {'Save registration_data to workspace'};
            app.SavetoworkspaceButton.Text = {'Save'; 'to workspace'};
            app.RemoveselectedrowsButton = uibutton(app.dataButtonGrid, 'push');
            app.RemoveselectedrowsButton.ButtonPushedFcn = createCallbackFcn(app, @removeSelectedRows, true);
            app.RemoveselectedrowsButton.Text = 'Remove selected rows';
            app.LoadfilesButton = uibutton(app.dataButtonGrid, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFile, true);
            app.LoadfilesButton.Text = 'Load files';
            app.LoadfolderButton = uibutton(app.dataButtonGrid, 'push');
            app.LoadfolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataDir, true);
            app.LoadfolderButton.Text = 'Load folder';
            app.SetapppreferencesButton = uibutton(app.dataButtonGrid, 'push');
            app.SetapppreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @SetapppreferencesButtonPushed, true);
            app.SetapppreferencesButton.Tooltip = {'This app uses a config (preferences).'; 'You can customize some settings there which change how the app automatic registration works and which data it will load.'};
            app.SetapppreferencesButton.Text = 'Set app preferences';
            app.ChoosepreferenceButton = uibutton(app.dataButtonGrid, 'push');
            app.ChoosepreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosepreferenceButtonPushed, true);
            app.ChoosepreferenceButton.Tooltip = {'When multiple preferences are available, you can change which one gets used'};
            app.ChoosepreferenceButton.Text = 'Choose preference';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ImageTab.ButtonDownFcn = createCallbackFcn(app, @updateImageTab, true);
            app.imageGrid = uigridlayout(app.ImageTab);
            app.UIAxes = uiaxes(app.imageGrid);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GridLayout11 = uigridlayout(app.imageGrid);
            app.GridLayout13 = uigridlayout(app.GridLayout11);
            app.UIAxes_corr = uiaxes(app.GridLayout13);
            title(app.UIAxes_corr, 'Image correlation')
            app.Label_corr = uilabel(app.GridLayout13);
            app.imageTable = uitable(app.GridLayout11);
            app.imageTable.ColumnName = '';
            app.imageTable.RowName = {};
            app.imageTable.CellEditCallback = createCallbackFcn(app, @imageSelect, true);
            app.GridLayout12 = uigridlayout(app.imageGrid);
            app.TabGroup2 = uitabgroup(app.GridLayout12);
            app.AutoTab = uitab(app.TabGroup2);
            app.AutoTab.Title = 'Auto';
            app.GridLayout_AutoMove = uigridlayout(app.AutoTab);
            app.RegisterimagesButton = uibutton(app.GridLayout_AutoMove, 'push');
            app.RegisterimagesButton.ButtonPushedFcn = createCallbackFcn(app, @runRegistration, true);
            app.RegisterimagesButton.Text = 'Register images';
            app.FullviewCheckBox = uicheckbox(app.GridLayout_AutoMove);
            app.FullviewCheckBox.Tooltip = {'When activated: Continuously ooms to fit all the data exactly.'};
            app.FullviewCheckBox.Text = 'Show full view';
            app.FullviewCheckBox.Value = true;
            app.ManualTab = uitab(app.TabGroup2);
            app.ManualTab.Tooltip = {'Directly edit the transformation matrix of manually moving recordings'};
            app.ManualTab.Title = 'Manual';
            app.ManualTab.ButtonDownFcn = createCallbackFcn(app, @UpdateManualMoveTab, true);
            app.GridLayoutManualMove = uigridlayout(app.ManualTab);
            app.UIAxesScrollManual = uiaxes(app.GridLayoutManualMove);
            app.UIAxesScrollManual.XTick = [];
            app.UIAxesScrollManual.YTick = [];
            app.UIAxesScrollManual.Visible = 'off';
            app.UITableTransform = uitable(app.GridLayoutManualMove);
            app.UITableTransform.ColumnName = {'1'; '2'; '3'};
            app.UITableTransform.RowName = {};
            app.UITableTransform.ColumnEditable = true;
            app.UITableTransform.CellEditCallback = createCallbackFcn(app, @UITableTransformCellEdit, true);
            app.SpeedofchangeviascrollSliderLabel = uilabel(app.GridLayoutManualMove);
            app.SpeedofchangeviascrollSliderLabel.Text = {'Speed of change'; 'via scroll'};
            app.SpeedofchangeviascrollSlider = uislider(app.GridLayoutManualMove);
            app.SpeedofchangeviascrollSlider.Limits = [0.6 5.4];
            app.SpeedofchangeviascrollSlider.MajorTicks = [1 5];
            app.SpeedofchangeviascrollSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedofchangeviascrollSliderValueChanged, true);
            app.SpeedofchangeviascrollSlider.MinorTicks = [1 2 3 4 5];
            app.SpeedofchangeviascrollSlider.Value = 1;
            app.CtrlptsTab = uitab(app.TabGroup2);
            app.CtrlptsTab.Title = 'Ctrl pts';
            app.GridLayout10 = uigridlayout(app.CtrlptsTab);
            app.UndolastButton = uibutton(app.GridLayout10, 'push');
            app.UndolastButton.ButtonPushedFcn = createCallbackFcn(app, @UndolastButtonPushed, true);
            app.UndolastButton.Tooltip = {'Limitation: Cannot recalculate transform if <3 control points remain.'};
            app.UndolastButton.Text = 'Undo last';
            app.RemovecontrolpointsButton = uibutton(app.GridLayout10, 'push');
            app.RemovecontrolpointsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovecontrolpointsButtonPushed, true);
            app.RemovecontrolpointsButton.Tooltip = {'Remove all control points for currently manually moving recordings'};
            app.RemovecontrolpointsButton.Text = {'Remove '; 'control points'};
            app.Label2 = uilabel(app.GridLayout10);
            app.Label2.Text = {'Left-click on main image to '; 'set moving point '; 'Left-click again to set reference point'; 'to which the moving point will '; 'attempt to move'; ''; 'Use 1, 3 or more control points.'; ''; 'Right click to delete closest control point'};
            app.SelectionAmountButtonGroup = uibuttongroup(app.GridLayout12);
            app.SelectionAmountButtonGroup.Title = 'Selection Amount';
            app.ColorSortingSwitch = uiswitch(app.SelectionAmountButtonGroup, 'slider');
            app.ColorSortingSwitch.Items = {'Color sort', 'Color retain'};
            app.ColorSortingSwitch.ValueChangedFcn = createCallbackFcn(app, @resetColor, true);
            app.ColorSortingSwitch.Tooltip = {'Sort colors based on ID nr, or keep colors to prevent change blindness'};
            app.ColorSortingSwitch.Value = 'Color sort';
            app.ResetcolorsCheckBox = uicheckbox(app.SelectionAmountButtonGroup);
            app.ResetcolorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @resetColor, true);
            app.ResetcolorsCheckBox.Tooltip = {'Recalculates colors for maximum contrast'};
            app.ResetcolorsCheckBox.Text = 'Reset colors';
            app.RemovingOldestSwitch = uiswitch(app.SelectionAmountButtonGroup, 'slider');
            app.RemovingOldestSwitch.Items = {'Removing oldest', 'Removing newest'};
            app.RemovingOldestSwitch.ValueChangedFcn = createCallbackFcn(app, @resetColor, true);
            app.RemovingOldestSwitch.Tooltip = {'When selecting different recordings while the selection amount is limited, which recording to unselect'};
            app.RemovingOldestSwitch.Value = 'Removing oldest';
            app.UseallCheckBox = uicheckbox(app.SelectionAmountButtonGroup);
            app.UseallCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseallCheckBoxValueChanged, true);
            app.UseallCheckBox.Text = 'Use all';
            app.UseNRecSpinner = uispinner(app.SelectionAmountButtonGroup);
            app.UseNRecSpinner.Limits = [0 Inf];
            app.UseNRecSpinner.ValueChangedFcn = createCallbackFcn(app, @UseNRecSpinnerValueChanged, true);
            app.imageVersionDropDown = uidropdown(app.GridLayout12);
            app.imageVersionDropDown.Items = {};
            app.imageVersionDropDown.ValueChangedFcn = createCallbackFcn(app, @dropDownImgRefresh, true);
            app.imageVersionDropDown.Placeholder = 'no image function given';
            app.imageVersionDropDown.Value = {};
            app.ResettransformsButton = uibutton(app.GridLayout12, 'push');
            app.ResettransformsButton.ButtonPushedFcn = createCallbackFcn(app, @resetTforms, true);
            app.ResettransformsButton.Tooltip = {'Reset transform of visible-, or manually moving recordings'};
            app.ResettransformsButton.Text = 'Reset transforms';
            app.SavetoworkspaceButton_2 = uibutton(app.GridLayout12, 'push');
            app.SavetoworkspaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveRegistration_data, true);
            app.SavetoworkspaceButton_2.Tooltip = {'Save registration_data to workspace'};
            app.SavetoworkspaceButton_2.Text = {'Save'; 'to workspace'};
            app.linkROIsTab = uitab(app.TabGroup);
            app.linkROIsTab.Title = 'link ROIs';
            app.linkROIsTab.ButtonDownFcn = createCallbackFcn(app, @updateROIsTab, true);
            app.GridLayoutLinkROIs = uigridlayout(app.linkROIsTab);
            app.UIAxesRoiLinks = uiaxes(app.GridLayoutLinkROIs);
            app.GridLayoutLinkROIsRight = uigridlayout(app.GridLayoutLinkROIs);
            app.UIAxesColorbar = uiaxes(app.GridLayoutLinkROIsRight);
            zlabel(app.UIAxesColorbar, 'Z')
            app.UIAxesColorbar.YTick = [];
            app.ThresholdSliderLabel = uilabel(app.GridLayoutLinkROIsRight);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ChronicSavetoFileButton = uibutton(app.GridLayoutLinkROIsRight, 'push');
            app.ChronicSavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChronicSavetoFileButtonPushed, true);
            app.ChronicSavetoFileButton.Text = {'Save '; 'to File'};
            app.LoadChronicEditsButton = uibutton(app.GridLayoutLinkROIsRight, 'push');
            app.LoadChronicEditsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChronicEditsButtonPushed, true);
            app.LoadChronicEditsButton.Tooltip = {'If you saved edited linkMat to the workspace using the ChronicViewer. Load it into this ImageRegGUI again.'};
            app.LoadChronicEditsButton.Text = {'Load edits '; 'from workspace'};
            app.ChronicViewerUIButton = uibutton(app.GridLayoutLinkROIsRight, 'push');
            app.ChronicViewerUIButton.ButtonPushedFcn = createCallbackFcn(app, @ChronicViewerUIButtonPushed, true);
            app.ChronicViewerUIButton.Text = {'Edit matches in'; 'ChronicViewerUI'};
            app.ChronicViewDropDown = uidropdown(app.GridLayoutLinkROIsRight);
            app.ChronicViewDropDown.Items = {'number of links', 'match ID', 'score'};
            app.ChronicViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ChronicViewDropDownValueChanged, true);
            app.ChronicViewDropDown.Value = 'number of links';
            app.ChronicReloadandRecalculatewhenswitchingtabsCheckBox = uicheckbox(app.GridLayoutLinkROIsRight);
            app.ChronicReloadandRecalculatewhenswitchingtabsCheckBox.Tooltip = {'Disabling this makes switching between tabs fast,'; 'and zooms the image the same when switching'};
            app.ChronicReloadandRecalculatewhenswitchingtabsCheckBox.Text = {'Reload and Recalculate'; 'when switching tabs'};
            app.ChronicReloadandRecalculatewhenswitchingtabsCheckBox.Value = true;
            app.ChronicThresholdSlider = uislider(app.GridLayoutLinkROIsRight);
            app.ChronicThresholdSlider.Limits = [40 100];
            app.ChronicThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ChronicThresholdSliderValueChanged, true);
            app.ChronicThresholdSlider.Value = 60;
            app.GridLayoutLinkROIsLeft = uigridlayout(app.GridLayoutLinkROIs);
            app.UIAxesRoiLinksSup3 = uiaxes(app.GridLayoutLinkROIsLeft);
            title(app.UIAxesRoiLinksSup3, 'ROI % matched')
            app.UIAxesRoiLinksSup3.YDir = 'reverse';
            app.UIAxesRoiLinksSup2 = uiaxes(app.GridLayoutLinkROIsLeft);
            title(app.UIAxesRoiLinksSup2, 'ROI % with n links')
            xlabel(app.UIAxesRoiLinksSup2, 'Number of links')
            app.UIAxesRoiLinksSup2.YDir = 'reverse';
            app.UIAxesRoiLinksSup1 = uiaxes(app.GridLayoutLinkROIsLeft);
            title(app.UIAxesRoiLinksSup1, 'ROI Overlap score')
            xlabel(app.UIAxesRoiLinksSup1, 'Number of links')
            ylabel(app.UIAxesRoiLinksSup1, 'Match''s overlap score')
            app.ShowingpercentagesButton = uibutton(app.GridLayoutLinkROIsLeft, 'push');
            app.ShowingpercentagesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowingpercentagesButtonPushed, true);
            app.ShowingpercentagesButton.Text = 'Showing percentages';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgRegMain

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end