classdef SetAnalysisSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout_TitleRow             matlab.ui.container.GridLayout
        TitleLabel                      matlab.ui.control.Label
        LoadexistingsettingsButton      matlab.ui.control.Button
        MainPanel_Location              matlab.ui.container.Panel
        GridLayout4_2                   matlab.ui.container.GridLayout
        DoLocationCheckBox              matlab.ui.control.CheckBox
        LocationSetButton               matlab.ui.control.Button
        LocationCheckButton             matlab.ui.control.Button
        MainPanel_EyeVideo              matlab.ui.container.Panel
        GridLayout4_3                   matlab.ui.container.GridLayout
        EyeVideo_DoCheckBox             matlab.ui.control.CheckBox
        EyeVideo_InvertCheckBox         matlab.ui.control.CheckBox
        EyeVideo_DeflickerCheckBox      matlab.ui.control.CheckBox
        MainPanel_LineShift             matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        LineShift_DoCheckBox            matlab.ui.control.CheckBox
        LineShift_DirectionButtonGroup  matlab.ui.container.ButtonGroup
        LineShift_DirectionHorizontalButton  matlab.ui.control.RadioButton
        LineShift_DirectionVerticalButton  matlab.ui.control.RadioButton
        GridLayout8                     matlab.ui.container.GridLayout
        LineShift_DetectButton          matlab.ui.control.Button
        LineShift_shiftValue            matlab.ui.container.GridLayout
        LineShift_shiftValueSpinner     matlab.ui.control.Spinner
        shiftSpinnerLabel               matlab.ui.control.Label
        UIAxes_LineShift                matlab.ui.control.UIAxes
        MainPanel_NoRMCorre             matlab.ui.container.Panel
        GridLayout_MainNoRMCorre        matlab.ui.container.GridLayout
        NoRMCorre_DoCheckBox            matlab.ui.control.CheckBox
        GridLayout10                    matlab.ui.container.GridLayout
        NoRMCorre_methodDropDown        matlab.ui.control.DropDown
        methodDropDownLabel             matlab.ui.control.Label
        NoRMCorre_CropxPanel            matlab.ui.container.Panel
        GridLayout_NoRMCorreX           matlab.ui.container.GridLayout
        xendSpinnerLabel                matlab.ui.control.Label
        xstartleftSpinnerLabel          matlab.ui.control.Label
        xstartleftSpinner               matlab.ui.control.Spinner
        xendSpinner                     matlab.ui.control.Spinner
        NoRMCorre_CropyPanel            matlab.ui.container.Panel
        GridLayout_NoRMCorreY           matlab.ui.container.GridLayout
        yendSpinnerLabel                matlab.ui.control.Label
        ystarttopSpinnerLabel           matlab.ui.control.Label
        ystarttopSpinner                matlab.ui.control.Spinner
        yendSpinner                     matlab.ui.control.Spinner
        UIAxes_NoRMCorreCrop            matlab.ui.control.UIAxes
        MainPanel_BackgroundSub         matlab.ui.container.Panel
        GridLayout_MainBackgroundSub    matlab.ui.container.GridLayout
        BackgroundSub_StartUIButton     matlab.ui.control.Button
        BackgroundSub_DoCheckBox        matlab.ui.control.CheckBox
        SmoothingsizeEditFieldLabel     matlab.ui.control.Label
        GaussianSmoothingLabel          matlab.ui.control.Label
        BackgroundSub_FilterTypeDropDown  matlab.ui.control.DropDown
        FiltertypeDropDownLabel         matlab.ui.control.Label
        BackgroundSub_FilterRadiusEditField  matlab.ui.control.NumericEditField
        BackgroundSub_FilterRadiusEditField_2  matlab.ui.control.NumericEditField
        innerradiusLabel                matlab.ui.control.Label
        BackgroundSub_ValueShifterEditField  matlab.ui.control.NumericEditField
        ValueshifterEditFieldLabel      matlab.ui.control.Label
        FilterradiusEditFieldLabel      matlab.ui.control.Label
        BackgroundSub_GaussianSmoothingDropDown  matlab.ui.control.DropDown
        BackgroundSub_SmoothingsizeEditField  matlab.ui.control.NumericEditField
        BackgroundSub_plotterCheckBox   matlab.ui.control.CheckBox
        MainPanel_GetRois               matlab.ui.container.Panel
        GridLayout4_5                   matlab.ui.container.GridLayout
        doRoi                           matlab.ui.control.CheckBox
        doRoi_loadSparButton            matlab.ui.control.Button
        doRoi_SetButton                 matlab.ui.control.Button
        doRoi_SparStatusLabel           matlab.ui.control.Label
        MainPanel_timedData             matlab.ui.container.Panel
        GridLayout_MainTimedData        matlab.ui.container.GridLayout
        timedFile_DoCheckBox            matlab.ui.control.CheckBox
        timedFile_SelectButton          matlab.ui.control.Button
        timedDataLabel                  matlab.ui.control.Label
        GridLayout_StartButtons         matlab.ui.container.GridLayout
        SavesettingstofileButton        matlab.ui.control.Button
        SettingstoworkspaceButton       matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        spar struct % Spectral PArameters/ Search PARameters for ROI detection
        do   struct % All other settings for AutomatedAnalysis
        files table % The files which will be analysed by AutomatedAnalysis
        nfiles double % The number of files that will be analysed 
        dataColor = cmapL('greenFancy', 256);
        hCropLines = gobjects(1,1);
        exampleData double = zeros(24, 24);
        hShiftLinesImg = gobjects(1,1);
    end
    
    methods (Access = private)
        function CollectDo(app)
            % Collect the to do settings from the app
            app.do(1).doSaveLocation = app.DoLocationCheckBox.Value;
            
            app.do.doEyeConvert  = app.EyeVideo_DoCheckBox.Value;
            app.do.EyeInvert  = app.EyeVideo_InvertCheckBox.Value;
            app.do.EyeDeFlicker  = app.EyeVideo_DeflickerCheckBox.Value;
            
            app.do.doLineShift     = app.LineShift_DoCheckBox.Value;
            app.do.LineShiftTrans   = app.LineShift_DirectionVerticalButton.Value;
            app.do.LineShiftValue   = app.LineShift_shiftValueSpinner.Value;
            
            app.do.doNoRMCorre        = app.NoRMCorre_DoCheckBox.Value;
            app.do.NoRMCorre.alignMethod = app.NoRMCorre_methodDropDown.Value;
            app.do.NoRMCorre.crop.x      = [app.xstartleftSpinner.Value, app.xendSpinner.Value];
            app.do.NoRMCorre.crop.y      = [app.ystarttopSpinner.Value,  app.yendSpinner.Value];
            
            app.do.doBackgroundSubtract = app.BackgroundSub_DoCheckBox.Value;
            if app.BackgroundSub_FilterTypeDropDown.ValueIndex==3
                app.do.BackgroundSubtract.FilterRadius  = [app.BackgroundSub_FilterRadiusEditField_2.Value,...
                                                           app.BackgroundSub_FilterRadiusEditField.Value];
            else
                app.do.BackgroundSubtract.FilterRadius = app.BackgroundSub_FilterRadiusEditField.Value;
            end
            app.do.BackgroundSubtract.filterMethod  = app.BackgroundSub_FilterTypeDropDown.Value;
            app.do.BackgroundSubtract.SmoothDim  = app.BackgroundSub_GaussianSmoothingDropDown.ValueIndex - 1;
            app.do.BackgroundSubtract.SmoothSe  = app.BackgroundSub_SmoothingsizeEditField.Value;
            app.do.BackgroundSubtract.plotter = app.BackgroundSub_plotterCheckBox.Value;
            
            app.do.getROIs = app.doRoi.Value;
            
            app.do.TimedData = app.timedFile_DoCheckBox.Value;
            app.do.timedFile = app.timedDataLabel.Text;
        end
        
        function succes = SetDo(app, indo)
            % Use the do settings from input to update the UI
            try
                if any(ismember(fieldnames(indo), 'do'))
                    indo = indo.do;                    
                end
                app.DoLocationCheckBox.Value = indo.doSaveLocation;
                
                app.EyeVideo_DoCheckBox.Value = indo.doEyeConvert;
                app.EyeVideo_InvertCheckBox.Value = indo.EyeInvert;
                app.EyeVideo_DeflickerCheckBox.Value = indo.EyeDeFlicker;
                
                app.LineShift_DoCheckBox.Value = indo.doLineShift;
                app.LineShift_DirectionVerticalButton.Value = indo.LineShiftTrans;
                app.LineShift_shiftValueSpinner.Value = indo.LineShiftValue;
                
                app.NoRMCorre_DoCheckBox.Value = indo.doNoRMCorre;
                app.NoRMCorre_methodDropDown.Value = indo.NoRMCorre.alignMethod;
                app.xstartleftSpinner.Value = indo.NoRMCorre.crop.x(1);
                app.xendSpinner.Value = min(indo.NoRMCorre.crop.x(2), app.xendSpinner.Limits(2));
                app.ystarttopSpinner.Value = indo.NoRMCorre.crop.y(1);
                app.yendSpinner.Value = min(indo.NoRMCorre.crop.y(2), app.yendSpinner.Limits(2));
                
                app.BackgroundSub_DoCheckBox.Value = indo.doBackgroundSubtract;
                app.BackgroundSub_FilterTypeDropDown.Value = indo.BackgroundSubtract.filterMethod;
                if app.BackgroundSub_FilterTypeDropDown.ValueIndex==3 && length(indo.BackgroundSubtract.FilterRadius)==2
                    app.BackgroundSub_FilterRadiusEditField_2.Value = indo.BackgroundSubtract.FilterRadius(1);
                end                
                app.BackgroundSub_FilterRadiusEditField.Value = indo.BackgroundSubtract.FilterRadius(end);
                app.BackgroundSub_GaussianSmoothingDropDown.ValueIndex = indo.BackgroundSubtract.SmoothDim + 1;
                app.BackgroundSub_SmoothingsizeEditField.Value = indo.BackgroundSubtract.SmoothSe;
                app.BackgroundSub_plotterCheckBox.Value = indo.BackgroundSubtract.plotter;
                
                app.doRoi.Value = indo.doGetROIs;
                
                app.timedFile_DoCheckBox.Value = indo.doTimedData;
                app.timedDataLabel.Text = indo.timedFile;
                succes = true;
            catch ME
                app.LoadexistingsettingsButton.Text = 'failed loading settings';
                succes = false;
                CollectDo(app)
                ReportStructBadness(indo, fieldnames(app.do))
                ME
            end
        end
        
        function ChooseOutFolders(app)
            % Choose a new output path for every file
            app.do.doSaveLocation = true;
            outputpathi = 'lalala';
            i = 1;
            while i <= app.nfiles && ischar(outputpathi)
                outputpathi = uigetdir(cd, sprintf('output path for file %d: %s',i, app.files.name(i)));
                if ischar(outputpathi)
                    app.files{i, 'pathOut'} = string([outputpathi '\']);
                end
                i = i + 1;
            end
            figure(app.UIFigure) % refocus on figure
            app.LocationSetButton.FontColor = 'k';
        end
        
        function DrawCropLines(app)
            x1 = app.xstartleftSpinner.Value;
            x2 = app.xendSpinner.Value;
            y1 = app.ystarttopSpinner.Value;
            y2 = app.yendSpinner.Value;
            x = [x1 x2 x2 x1 x1];
            y = [y1 y1 y2 y2 y1];
            app.hCropLines.XData = x;
            app.hCropLines.YData = y;
        end
        
        function LineShiftWarningLight(app)
            % Displays the shift and checkbox in red if a shift should be
            % applied but the analysis step is turned off
            if ~app.LineShift_DoCheckBox.Value && app.LineShift_shiftValueSpinner.Value~=0
                app.LineShift_DoCheckBox.FontColor = 'r';
                app.LineShift_shiftValueSpinner.FontColor = 'r';
            else
                app.LineShift_DoCheckBox.FontColor = 'k';
                app.LineShift_shiftValueSpinner.FontColor = 'k';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, files, do, spar)
            arguments
                app
                files = table([]);
                do = [];
                spar = []
            end
            
            if isempty(files)
                files = SelectFiles();
                figure(app.UIFigure) % refocus on figure
            end
            app.files = files;
            if istable(files)
                app.nfiles = height(files);
            else
                app.nfiles = length(files);
            end
            
            if isempty(spar)
                if exist('spar.mat', 'file')
                    app.spar = load('spar.mat');
                    app.doRoi_SparStatusLabel.Text = 'spar loaded from current folder';
                end
            else % not empty spar input
                app.doRoi_SparStatusLabel.Text = 'Got an input for spar';
            end
            app.spar = spar;
            
            
            if ~isempty(do) % Use the input do to update the UI
                gotDoInput = SetDo(app, do);
            else % no do input given: initialize the do
                CollectDo(app);
                gotDoInput = false;
            end
            
            clearvars -global
            % if app.files.name
            if app.nfiles>0
                app.exampleData = sbxread(fullfile([app.files.path{1}, app.files.name{1}]), 10, 200);
                app.exampleData = mean(squeeze(app.exampleData),3);
                global info % info got initialized by sbxread
                
                % Test and show line shift situation
                LineShift_DetectButtonPushed(app, [])
                
                % Get crop limits from data
                dims = size(app.exampleData);
                [dwidth, dheight] = SbxGetDims(info);
                if dwidth ~= dims(2)
                    warning('size of loaded sbx example frames is different than expected from other sbx reading function')
                    dims
                    [dheight dwidth]
                end
                % Set crop limits in app
                app.ystarttopSpinner.Limits = [1, dheight];
                app.xstartleftSpinner.Limits = [1, dwidth];
                app.yendSpinner.Limits = [1, dheight];
                app.xendSpinner.Limits = [1, dwidth];
                if ~gotDoInput
                    app.yendSpinner.Value = dheight;
                    app.xendSpinner.Value = dwidth;
                end
                
                % Initialize NORMCORRe crop example image
                normCorColor = MidtoneBalance(app.dataColor, 0.25);
                imagesc(app.UIAxes_NoRMCorreCrop, app.exampleData)
                app.UIAxes_NoRMCorreCrop.XTick = [0, dims(2)];
                app.UIAxes_NoRMCorreCrop.YTick = [0, dims(1)];
                app.UIAxes_NoRMCorreCrop.XLim  = [0, dims(2)];
                app.UIAxes_NoRMCorreCrop.YLim  = [0, dims(1)];
                set(app.UIAxes_NoRMCorreCrop, 'colormap', normCorColor,...
                                              'NextPlot', 'add')
                app.hCropLines = plot(app.UIAxes_NoRMCorreCrop, nan, nan, 'r');
                DrawCropLines(app)
                
                % Check if output folders are existing if saveLocation
                if app.do.doSaveLocation && ~isfield(app.files.pathOut)
                    app.LocationSetButton.FontColor = 'r';
                end
            end
            
            if isscalar(dbstack)
                app.STARTButton.Tooltip = 'Detected as run via command line/ script, will start AutomatedAnalysis';
            else
                app.STARTButton.Tooltip = 'Detected as run via function. Will save settings to that workspace and let the function continue';
            end
        end

        % Button pushed function: LoadexistingsettingsButton
        function LoadexistingsettingsButtonPushed(app, event)
            [doName, doPath] = uigetfile('.mat', 'load existing do struct');
            if ischar(doName)
                indo = load([doPath doName]);
                succes = SetDo(app, indo);
                if succes
                    app.LoadexistingsettingsButton.Text = {'settings from:' doPath};
                end
            else
                app.LoadexistingsettingsButton.Text = 'Aborted loading settings';
            end
            figure(app.UIFigure) % refocus on figure

        end

        % Value changed function: DoLocationCheckBox
        function DoLocationCheckBoxValueChanged(app, event)
            value = app.DoLocationCheckBox.Value;
            
            if value && ~ismember('pathOut', app.files.Properties.VariableNames)
                ChooseOutFolders(app)
                % app.DoLocationCheckBox.Value = true;
            end
        end

        % Button pushed function: LocationSetButton
        function LocationSetButtonPushed(app, event)
            ChooseOutFolders(app)
        end

        % Button pushed function: LocationCheckButton
        function LocationCheckButtonPushed(app, event)
            % Show variable using the MATLAB variable viewer
            assignin("base", 'filesToAnalyse', app.files)
            openvar('filesToAnalyse')
            
            varNames = app.files.Properties.VariableNames;
            columnName = 'name';
            varNames(strcmp(varNames, columnName)) = [];
            nVars = length(varNames);
            filesTrans = cellstr(app.files.(varNames{1}))';
            for i = 2:nVars
                filesTrans = [filesTrans; cellstr(app.files.(varNames{i}))'];
            end
            filesTrans = cell2table(filesTrans, ...
                                    'VariableNames', matlab.lang.makeValidName(app.files.(columnName)), ...
                                    'RowNames', varNames);
            assignin("base", 'filesToAnalyseTransposed', filesTrans)
            openvar('filesToAnalyseTransposed')
        end

        % Value changed function: LineShift_DoCheckBox
        function LineShift_DoCheckBoxValueChanged(app, event)
            LineShiftWarningLight(app)
        end

        % Button pushed function: LineShift_DetectButton
        function LineShift_DetectButtonPushed(app, event)
            im = app.exampleData;
            trans = app.LineShift_DirectionVerticalButton.Value;
            doPlots = false;
            [s, im, bestS] = ShiftLinesCheck(im, trans, [], doPlots, doPlots, []);
            app.LineShift_shiftValueSpinner.Value = s;
            
            dimim = size(im);
            
            [~, maxIdxY] = max(mean(im, 2));
            
            ylims = [max(maxIdxY-6, 0),...
                     min(dimim(1), maxIdxY+6)];
            imCut = im(ylims(1):ylims(2),:);
            [~, maxIdxX] = max(mean(imCut,1));
            xlims = [max(maxIdxX-50, 0),...
                     min(dimim(2), maxIdxX+50)];
            app.hShiftLinesImg = imagesc(app.UIAxes_LineShift, im);
            ylim(app.UIAxes_LineShift, ylims)
            xlim(app.UIAxes_LineShift, xlims)
            set(app.UIAxes_LineShift, 'Colormap', app.dataColor)
            title(app.UIAxes_LineShift, sprintf('correlation: %.3f', bestS))
            
            LineShiftWarningLight(app)
        end

        % Callback function: LineShift_DirectionButtonGroup, 
        % ...and 1 other component
        function LineShift_shiftValueSpinnerValueChanged(app, event)
            value = app.LineShift_shiftValueSpinner.Value;
            trans = app.LineShift_DirectionVerticalButton.Value;
            doPlots = false;
            [~, img, bestS] = ShiftLinesCheck(app.exampleData, trans, [], doPlots, doPlots, value);
            app.hShiftLinesImg.CData = img;
            title(app.UIAxes_LineShift, sprintf('correlation: %.3f', bestS))
            LineShiftWarningLight(app)
        end

        % Value changed function: xendSpinner, xstartleftSpinner, 
        % ...and 2 other components
        function cropValueChanged(app, event)
            if app.nfiles>0
                DrawCropLines(app)
            end
        end

        % Button pushed function: BackgroundSub_StartUIButton
        function BackgroundSub_StartUIButtonPushed(app, event)
            edit BackgroundSubtractSbxExampleFrame.m
        end

        % Button pushed function: doRoi_SetButton
        function doRoi_SetButtonPushed(app, event)
           app.doRoi_SparStatusLabel.Text = {'Checking spar', 'via Spectroiparm'};
           [app.spar, succesBool] = Spectroiparm(app.spar);
           figure(app.UIFigure) % refocus on figure
           if succesBool
               app.doRoi_SparStatusLabel.Text = {'Updated spar', 'and saved in current folder'};
           else
               app.doRoi_SparStatusLabel.Text = {'no changes made to spar'};
           end
        end

        % Button pushed function: doRoi_loadSparButton
        function doRoi_loadSparButtonPushed(app, event)
            [sparFile, sparPath] = uigetfile('*.mat', 'get spar file', 'spar.mat');
            app.spar = load(fullfile(sparPath, sparFile));
            app.doRoi_SparStatusLabel.Text = {'spar loaded', sparPath};
        end

        % Button pushed function: SettingstoworkspaceButton
        function SettingstoworkspaceButtonPushed(app, event)
            CollectDo(app)
            assignin("base", 'do', app.do)
            assignin("base", 'files', app.files)
            assignin("base", 'nfiles', app.nfiles)
            assignin("base", 'spar', app.spar)
        end

        % Button pushed function: SavesettingstofileButton
        function SavesettingstofileButtonPushed(app, event)
            outdo = app.do;
            save("do.mat", '-struct', "outdo")
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            CollectDo(app)
            if isscalar(dbstack) % Called from base workspace
                AutomatedAnalysis(app.do, app.files, app.spar)
            else % put variables in workspace of AutomatedAnalysis, 
                                 % which is what called SetAnalysisSettings
                assignin("caller", 'do', app.do)
                assignin("caller", 'files', app.files)
                assignin("caller", 'spar', app.spar)
            end
            delete(app)
        end

        % Value changed function: BackgroundSub_FilterTypeDropDown
        function BackgroundSub_FilterTypeDropDownValueChanged(app, event)
            if app.BackgroundSub_FilterTypeDropDown.ValueIndex==3
                app.BackgroundSub_FilterRadiusEditField_2.Visible = true;
            else
                app.BackgroundSub_FilterRadiusEditField_2.Visible = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_StartButtons = uigridlayout(app.GridLayout);
            app.STARTButton = uibutton(app.GridLayout_StartButtons, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Tooltip = {'GO'};
            app.STARTButton.Text = 'START';
            app.SettingstoworkspaceButton = uibutton(app.GridLayout_StartButtons, 'push');
            app.SettingstoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SettingstoworkspaceButtonPushed, true);
            app.SettingstoworkspaceButton.Tooltip = {'If you save the settings, called ''do'', to workspace, you can safely and easily execute specific sections of AutomatedAnalysis'};
            app.SettingstoworkspaceButton.Text = 'Settings to workspace';
            app.SavesettingstofileButton = uibutton(app.GridLayout_StartButtons, 'push');
            app.SavesettingstofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingstofileButtonPushed, true);
            app.SavesettingstofileButton.Tooltip = {'If you save the settings, called ''do'', to workspace, you can safely and easily execute specific sections of AutomatedAnalysis'};
            app.SavesettingstofileButton.Text = 'Save settings to file';
            app.MainPanel_timedData = uipanel(app.GridLayout);
            app.MainPanel_timedData.Title = 'Save analysis log / timing info';
            app.GridLayout_MainTimedData = uigridlayout(app.MainPanel_timedData);
            app.timedDataLabel = uilabel(app.GridLayout_MainTimedData);
            app.timedDataLabel.Text = 'timedData.mat';
            app.timedFile_SelectButton = uibutton(app.GridLayout_MainTimedData, 'push');
            app.timedFile_SelectButton.Text = 'Select file';
            app.timedFile_DoCheckBox = uicheckbox(app.GridLayout_MainTimedData);
            app.timedFile_DoCheckBox.Text = 'Save time info';
            app.MainPanel_GetRois = uipanel(app.GridLayout);
            app.MainPanel_GetRois.Title = 'Detect ROIs';
            app.GridLayout4_5 = uigridlayout(app.MainPanel_GetRois);
            app.doRoi_SparStatusLabel = uilabel(app.GridLayout4_5);
            app.doRoi_SparStatusLabel.Tooltip = {'Spectral PARameters/ Search PARameters, for ROI detection'};
            app.doRoi_SparStatusLabel.Text = 'spar presence not checked';
            app.doRoi_SetButton = uibutton(app.GridLayout4_5, 'push');
            app.doRoi_SetButton.ButtonPushedFcn = createCallbackFcn(app, @doRoi_SetButtonPushed, true);
            app.doRoi_SetButton.Text = {'Set/ check '; 'ROI detection settings'};
            app.doRoi_loadSparButton = uibutton(app.GridLayout4_5, 'push');
            app.doRoi_loadSparButton.ButtonPushedFcn = createCallbackFcn(app, @doRoi_loadSparButtonPushed, true);
            app.doRoi_loadSparButton.Text = {'Load ROI detection settings'; '(spar)'};
            app.doRoi = uicheckbox(app.GridLayout4_5);
            app.doRoi.Text = 'Do ROI detection';
            app.MainPanel_BackgroundSub = uipanel(app.GridLayout);
            app.MainPanel_BackgroundSub.Title = 'Background subtraction. For 1 photon or miniscope only';
            app.GridLayout_MainBackgroundSub = uigridlayout(app.MainPanel_BackgroundSub);
            app.BackgroundSub_plotterCheckBox = uicheckbox(app.GridLayout_MainBackgroundSub);
            app.BackgroundSub_plotterCheckBox.Text = {'plot last'; 'frame'};
            app.BackgroundSub_SmoothingsizeEditField = uieditfield(app.GridLayout_MainBackgroundSub, 'numeric');
            app.BackgroundSub_SmoothingsizeEditField.Tooltip = {'Standard deviation size of the one directional filtering gaussian '};
            app.BackgroundSub_GaussianSmoothingDropDown = uidropdown(app.GridLayout_MainBackgroundSub);
            app.BackgroundSub_GaussianSmoothingDropDown.Items = {'none', 'horizontal smoothing', 'vertical smoothing'};
            app.BackgroundSub_GaussianSmoothingDropDown.Tooltip = {'If extreme banding artifacts in one direction are present, the data might benefit from smoothing over the perpendicular direction.'};
            app.BackgroundSub_GaussianSmoothingDropDown.Value = 'none';
            app.FilterradiusEditFieldLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.FilterradiusEditFieldLabel.Text = 'Filter radius';
            app.ValueshifterEditFieldLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.ValueshifterEditFieldLabel.Text = 'Value shifter';
            app.BackgroundSub_ValueShifterEditField = uieditfield(app.GridLayout_MainBackgroundSub, 'numeric');
            app.BackgroundSub_ValueShifterEditField.Tooltip = {'By subtracting, values can go below 0. '; 'These values would be cut off at 0. Increasing the shifter value prevents this by summing this value to every pixel.'};
            app.BackgroundSub_ValueShifterEditField.Value = 8000;
            app.innerradiusLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.innerradiusLabel.Visible = 'off';
            app.innerradiusLabel.Text = 'inner radius';
            app.BackgroundSub_FilterRadiusEditField_2 = uieditfield(app.GridLayout_MainBackgroundSub, 'numeric');
            app.BackgroundSub_FilterRadiusEditField_2.Visible = 'off';
            app.BackgroundSub_FilterRadiusEditField_2.Value = 2;
            app.BackgroundSub_FilterRadiusEditField = uieditfield(app.GridLayout_MainBackgroundSub, 'numeric');
            app.BackgroundSub_FilterRadiusEditField.Value = 15;
            app.FiltertypeDropDownLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.FiltertypeDropDownLabel.Text = 'Filter type';
            app.BackgroundSub_FilterTypeDropDown = uidropdown(app.GridLayout_MainBackgroundSub);
            app.BackgroundSub_FilterTypeDropDown.Items = {'Circular Average', 'Gaussian Average', 'Donut'};
            app.BackgroundSub_FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundSub_FilterTypeDropDownValueChanged, true);
            app.BackgroundSub_FilterTypeDropDown.Value = 'Circular Average';
            app.GaussianSmoothingLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.GaussianSmoothingLabel.Text = {'Gaussian'; ' Smoothing'};
            app.SmoothingsizeEditFieldLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.SmoothingsizeEditFieldLabel.Text = 'Smoothing size';
            app.BackgroundSub_DoCheckBox = uicheckbox(app.GridLayout_MainBackgroundSub);
            app.BackgroundSub_DoCheckBox.Text = 'Do background subtraction';
            app.BackgroundSub_StartUIButton = uibutton(app.GridLayout_MainBackgroundSub, 'push');
            app.BackgroundSub_StartUIButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundSub_StartUIButtonPushed, true);
            app.BackgroundSub_StartUIButton.Tooltip = {'Start separate GUI'};
            app.BackgroundSub_StartUIButton.Text = 'Check parameters';
            app.MainPanel_NoRMCorre = uipanel(app.GridLayout);
            app.MainPanel_NoRMCorre.Title = 'NoRMCorre Motion correction';
            app.GridLayout_MainNoRMCorre = uigridlayout(app.MainPanel_NoRMCorre);
            app.UIAxes_NoRMCorreCrop = uiaxes(app.GridLayout_MainNoRMCorre);
            app.UIAxes_NoRMCorreCrop.XTick = [];
            app.UIAxes_NoRMCorreCrop.YTick = [];
            app.NoRMCorre_CropyPanel = uipanel(app.GridLayout_MainNoRMCorre);
            app.NoRMCorre_CropyPanel.Title = 'Crop y';
            app.GridLayout_NoRMCorreY = uigridlayout(app.NoRMCorre_CropyPanel);
            app.yendSpinner = uispinner(app.GridLayout_NoRMCorreY);
            app.yendSpinner.Limits = [1 Inf];
            app.yendSpinner.RoundFractionalValues = 'on';
            app.yendSpinner.ValueChangedFcn = createCallbackFcn(app, @cropValueChanged, true);
            app.yendSpinner.Value = 512;
            app.ystarttopSpinner = uispinner(app.GridLayout_NoRMCorreY);
            app.ystarttopSpinner.Limits = [1 Inf];
            app.ystarttopSpinner.RoundFractionalValues = 'on';
            app.ystarttopSpinner.ValueChangedFcn = createCallbackFcn(app, @cropValueChanged, true);
            app.ystarttopSpinner.Value = 5;
            app.ystarttopSpinnerLabel = uilabel(app.GridLayout_NoRMCorreY);
            app.ystarttopSpinnerLabel.Text = 'y start (top)';
            app.yendSpinnerLabel = uilabel(app.GridLayout_NoRMCorreY);
            app.yendSpinnerLabel.Text = 'y end';
            app.NoRMCorre_CropxPanel = uipanel(app.GridLayout_MainNoRMCorre);
            app.NoRMCorre_CropxPanel.Title = 'Crop x';
            app.GridLayout_NoRMCorreX = uigridlayout(app.NoRMCorre_CropxPanel);
            app.xendSpinner = uispinner(app.GridLayout_NoRMCorreX);
            app.xendSpinner.Limits = [1 Inf];
            app.xendSpinner.RoundFractionalValues = 'on';
            app.xendSpinner.ValueChangedFcn = createCallbackFcn(app, @cropValueChanged, true);
            app.xendSpinner.Value = 796;
            app.xstartleftSpinner = uispinner(app.GridLayout_NoRMCorreX);
            app.xstartleftSpinner.Limits = [1 Inf];
            app.xstartleftSpinner.RoundFractionalValues = 'on';
            app.xstartleftSpinner.ValueChangedFcn = createCallbackFcn(app, @cropValueChanged, true);
            app.xstartleftSpinner.Value = 2;
            app.xstartleftSpinnerLabel = uilabel(app.GridLayout_NoRMCorreX);
            app.xstartleftSpinnerLabel.Text = 'x start (left)';
            app.xendSpinnerLabel = uilabel(app.GridLayout_NoRMCorreX);
            app.xendSpinnerLabel.Text = 'x end';
            app.GridLayout10 = uigridlayout(app.GridLayout_MainNoRMCorre);
            app.methodDropDownLabel = uilabel(app.GridLayout10);
            app.methodDropDownLabel.Text = 'method';
            app.NoRMCorre_methodDropDown = uidropdown(app.GridLayout10);
            app.NoRMCorre_methodDropDown.Items = {'Rigid', 'Nonrigid'};
            app.NoRMCorre_methodDropDown.Value = 'Rigid';
            app.NoRMCorre_DoCheckBox = uicheckbox(app.GridLayout_MainNoRMCorre);
            app.NoRMCorre_DoCheckBox.Text = 'Do motion correction';
            app.NoRMCorre_DoCheckBox.Value = true;
            app.MainPanel_LineShift = uipanel(app.GridLayout);
            app.MainPanel_LineShift.Title = 'Line shift, to correct for bidirection scanning misalignment';
            app.GridLayout2 = uigridlayout(app.MainPanel_LineShift);
            app.UIAxes_LineShift = uiaxes(app.GridLayout2);
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.LineShift_shiftValue = uigridlayout(app.GridLayout8);
            app.shiftSpinnerLabel = uilabel(app.LineShift_shiftValue);
            app.shiftSpinnerLabel.Text = 'shift';
            app.LineShift_shiftValueSpinner = uispinner(app.LineShift_shiftValue);
            app.LineShift_shiftValueSpinner.ValueChangedFcn = createCallbackFcn(app, @LineShift_shiftValueSpinnerValueChanged, true);
            app.LineShift_DetectButton = uibutton(app.GridLayout8, 'push');
            app.LineShift_DetectButton.ButtonPushedFcn = createCallbackFcn(app, @LineShift_DetectButtonPushed, true);
            app.LineShift_DetectButton.Text = 'Detect misalignment';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.LineShift_DirectionButtonGroup = uibuttongroup(app.GridLayout9);
            app.LineShift_DirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LineShift_shiftValueSpinnerValueChanged, true);
            app.LineShift_DirectionButtonGroup.Title = 'Line artifact direction';
            app.LineShift_DirectionVerticalButton = uiradiobutton(app.LineShift_DirectionButtonGroup);
            app.LineShift_DirectionVerticalButton.Text = 'Vertical';
            app.LineShift_DirectionHorizontalButton = uiradiobutton(app.LineShift_DirectionButtonGroup);
            app.LineShift_DirectionHorizontalButton.Text = 'Horizontal';
            app.LineShift_DirectionHorizontalButton.Value = true;
            app.LineShift_DoCheckBox = uicheckbox(app.GridLayout9);
            app.LineShift_DoCheckBox.ValueChangedFcn = createCallbackFcn(app, @LineShift_DoCheckBoxValueChanged, true);
            app.LineShift_DoCheckBox.Text = 'Do line shift';
            app.MainPanel_EyeVideo = uipanel(app.GridLayout);
            app.MainPanel_EyeVideo.Title = 'Detect mat file of eye data to convert to mp4 video?';
            app.GridLayout4_3 = uigridlayout(app.MainPanel_EyeVideo);
            app.EyeVideo_DeflickerCheckBox = uicheckbox(app.GridLayout4_3);
            app.EyeVideo_DeflickerCheckBox.Text = 'Deflicker video ';
            app.EyeVideo_InvertCheckBox = uicheckbox(app.GridLayout4_3);
            app.EyeVideo_InvertCheckBox.Text = 'Invert brightness';
            app.EyeVideo_DoCheckBox = uicheckbox(app.GridLayout4_3);
            app.EyeVideo_DoCheckBox.Text = 'Do eye data file conversion';
            app.MainPanel_Location = uipanel(app.GridLayout);
            app.MainPanel_Location.Title = 'Select analysis output location ';
            app.GridLayout4_2 = uigridlayout(app.MainPanel_Location);
            app.LocationCheckButton = uibutton(app.GridLayout4_2, 'push');
            app.LocationCheckButton.ButtonPushedFcn = createCallbackFcn(app, @LocationCheckButtonPushed, true);
            app.LocationCheckButton.Text = 'Check locations';
            app.LocationSetButton = uibutton(app.GridLayout4_2, 'push');
            app.LocationSetButton.ButtonPushedFcn = createCallbackFcn(app, @LocationSetButtonPushed, true);
            app.LocationSetButton.Text = 'Set locations';
            app.DoLocationCheckBox = uicheckbox(app.GridLayout4_2);
            app.DoLocationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoLocationCheckBoxValueChanged, true);
            app.DoLocationCheckBox.Text = 'Save in other location';
            app.GridLayout_TitleRow = uigridlayout(app.GridLayout);
            app.LoadexistingsettingsButton = uibutton(app.GridLayout_TitleRow, 'push');
            app.LoadexistingsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsettingsButtonPushed, true);
            app.LoadexistingsettingsButton.Text = 'Load existing settings';
            app.TitleLabel = uilabel(app.GridLayout_TitleRow);
            app.TitleLabel.Text = 'Set Analysis settings of Spectral Segmentation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetAnalysisSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end